classdef ExpreimentGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IntroTab                        matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        BeginButton                     matlab.ui.control.Button
        ConfigurationTab                matlab.ui.container.Tab
        GenerateDropDownLabel           matlab.ui.control.Label
        GenerateDropDown                matlab.ui.control.DropDown
        Tree                            matlab.ui.container.Tree
        Event1Node                      matlab.ui.container.TreeNode
        NewEventButton                  matlab.ui.control.Button
        continuetofinishButton          matlab.ui.control.Button
        AddtoEventButton                matlab.ui.control.Button
        PanelSignalPureTone             matlab.ui.container.Panel
        PhaseDropDownLabel              matlab.ui.control.Label
        PhaseDropDown                   matlab.ui.control.DropDown
        FrequencyHzDropDownLabel        matlab.ui.control.Label
        FrequencyHzDropDown             matlab.ui.control.DropDown
        LeveldBEditFieldLabel           matlab.ui.control.Label
        LeveldBEditField                matlab.ui.control.NumericEditField
        onsetmsecEditFieldLabel         matlab.ui.control.Label
        onsetmsecEditField              matlab.ui.control.NumericEditField
        offsetmsecEditFieldLabel        matlab.ui.control.Label
        offsetmsecEditField             matlab.ui.control.NumericEditField
        ModulationPanel                 matlab.ui.container.Panel
        FMModulationCheckBox            matlab.ui.control.CheckBox
        ModulationIndexEditFieldLabel   matlab.ui.control.Label
        ModulationIndexEditField        matlab.ui.control.NumericEditField
        AMModulationCheckBox            matlab.ui.control.CheckBox
        depth0100EditFieldLabel         matlab.ui.control.Label
        depth0100EditField              matlab.ui.control.NumericEditField
        ModFreqHzEditFieldLabel         matlab.ui.control.Label
        ModFreqHzEditField              matlab.ui.control.NumericEditField
        DurationmsecDropDownLabel       matlab.ui.control.Label
        DurationmsecDropDown            matlab.ui.control.DropDown
        EnterValueinmsecEditFieldLabel  matlab.ui.control.Label
        EnterValueinmsecEditField       matlab.ui.control.NumericEditField
        OutputSelectionPanel            matlab.ui.container.Panel
        TXFF1                           matlab.ui.control.CheckBox
        TXFF2                           matlab.ui.control.CheckBox
        TXFF3                           matlab.ui.control.CheckBox
        TXFF4                           matlab.ui.control.CheckBox
        TXFF8                           matlab.ui.control.CheckBox
        TXFF7                           matlab.ui.control.CheckBox
        TXFF6                           matlab.ui.control.CheckBox
        TXFF5                           matlab.ui.control.CheckBox
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        ActivateRandomSpeakersCheckBox  matlab.ui.control.CheckBox
        HowmanyspeakerstoselectrandomEditFieldLabel  matlab.ui.control.Label
        HowmanyspeakerstoselectrandomEditField  matlab.ui.control.NumericEditField
        PanelNoise                      matlab.ui.container.Panel
        ChooseDropDownLabel             matlab.ui.control.Label
        ChooseDropDown                  matlab.ui.control.DropDown
        phasedegDropDownLabel           matlab.ui.control.Label
        phasedegDropDown                matlab.ui.control.DropDown
        LeveldBEditField_2Label         matlab.ui.control.Label
        LeveldBEditField_2              matlab.ui.control.NumericEditField
        cntrfreqHzEditFieldLabel        matlab.ui.control.Label
        cntrfreqHzEditField             matlab.ui.control.NumericEditField
        BWHzEditFieldLabel              matlab.ui.control.Label
        BWHzEditField                   matlab.ui.control.NumericEditField
        FinishTab                       matlab.ui.container.Tab
        ContextMenu                     matlab.ui.container.ContextMenu
        OpenMenu                        matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        DeleteMenu_2                    matlab.ui.container.Menu
    end

    
    properties (Access = private)
        script 
        index_event = 1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddtoEventButton
        function AddtoEventButtonPushed(app, event)
            selected = app.Tree.SelectedNodes;
            if(isempty(selected))
                uialert(app.UIFigure,"Please select an event before adding","No Event");
                return;
            end
            if(~strcmp(selected.Tag,'event'))
                uialert(app.UIFigure,"Please select event (do not confuse with signal)","Not an Event");
                return;
            end
            if(length(selected.Children)>=3)
                uialert(app.UIFigure,"More then 3 signals to the same event","Too much signals");
                return;
            end
            if(~any(arrayfun(@(ii)app.("TXFF"+ii).Value,[1:8])))
                uialert(app.UIFigure,"Please select a speaker","No Speaker Selection");
                return;
            end
            selected.NodeData = {app.DurationmsecDropDown.Items app.EnterValueinmsecEditField.Value};
            cell = CellPureToneMaker(app);
            %TODO add noise from UI and to UI
            %TODO add speech from UI and to UI
            %TODO add the finish the generate 
            %add the wait and pause commands
            %add complex expriements
            uitreenode(selected,'Text',app.GenerateDropDown.Value,'NodeData',cell,"ContextMenu",app.ContextMenu,'Tag','signal');
            

        end

        % Value changed function: FMModulationCheckBox
        function FMModulationCheckBoxValueChanged(app, event)
            value = app.FMModulationCheckBox.Value;
            app.ModulationIndexEditField.Enable = value;
        end

        % Value changed function: AMModulationCheckBox
        function AMModulationCheckBoxValueChanged(app, event)
            value = app.AMModulationCheckBox.Value;
            app.depth0100EditField.Enable = value;
        end

        % Value changed function: ActivateRandomSpeakersCheckBox
        function ActivateRandomSpeakersCheckBoxValueChanged(app, event)
            value = app.ActivateRandomSpeakersCheckBox.Value;
            if(any(arrayfun(@(ii) app.("TXFF"+ii).Value,[1:8])))
                app.HowmanyspeakerstoselectrandomEditField.Enable = value;
                upperLimit = length(find(arrayfun(@(ii) app.("TXFF"+ii).Value,[1:8])==1));
                app.HowmanyspeakerstoselectrandomEditField.Limits = [0,upperLimit];
            else
                uialert(app.UIFigure,"No selected Speaker","No selected Speaker");
                return;
            end
        end

        % Value changed function: 
        % HowmanyspeakerstoselectrandomEditField
        function HowmanyspeakerstoselectrandomEditFieldValueChanged(app, event)
            value = app.HowmanyspeakerstoselectrandomEditField.Value;
            upperLimit = length(find(arrayfun(@(ii) app.("TXFF"+ii).Value,[1:8])==1));
            app.HowmanyspeakerstoselectrandomEditField.Limits = [0,upperLimit];
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            selected = app.Tree.SelectedNodes;
            if(isempty(selected))
                uialert(app.UIFigure,"Please Click on the signal and then right click for action","Nothing selected");
                return;
            end
            app.GenerateDropDown.Value = selected.Text;
            GenerateDropDownValueChanged(app,event);
            app.DurationmsecDropDown.Items = selected.Parent.NodeData{1};
            app.EnterValueinmsecEditField.Value = selected.Parent.NodeData{end};
            cell = selected.NodeData;
            OpenPureTone(app,cell);

            
        end

        % Value changed function: GenerateDropDown
        function GenerateDropDownValueChanged(app, event)
            app.ActivateRandomSpeakersCheckBox.Value = 0;
            arrayfun(@(ii) setfield(app.("TXFF"+ii),'Value' ,0),[1:8]);
            
            value = app.GenerateDropDown.Value;
            app.AddtoEventButton.Enable = 'on';
           switch value
               case 'None'
                    app.PanelSignalPureTone.Enable ='off';
                    app.PanelSignalPureTone.Visible='off';
                    app.ModulationPanel.Enable = 'off';
                    app.ModulationPanel.Visible ='off';
                    app.AddtoEventButton.Enable = 'off';
                   app.PanelNoise.Visible = 'off';
                   app.PanelNoise.Enable = 'off';
               case 'Pure Tone'
                    app.PanelSignalPureTone.Enable ='on';
                    app.PanelSignalPureTone.Visible='on';
                    app.ModulationPanel.Enable = 'on';
                    app.ModulationPanel.Visible ='on';
                    
               case 'Noise'
                   app.PanelNoise.Visible = 'on';
                   app.PanelNoise.Enable = 'on';
                   
                            
            end
        end

        % Menu selected function: DeleteMenu, DeleteMenu_2
        function DeleteMenuSelected(app, event)
            selected = app.Tree.SelectedNodes;
            if(isempty(selected))
                uialert(app.UIFigure,"Please Click on the signal and then right click for action","Nothing selected");
                return;
            end
            selected.delete;
        end

        % Button pushed function: NewEventButton
        function NewEventButtonPushed(app, event)
            app.index_event = app.index_event + 1;
            if(~isempty(app.Tree.SelectedNodes)&&strcmp(app.Tree.SelectedNodes.Tag,'event'))
                uitreenode(app.Tree,app.Tree.SelectedNodes,'Text',"Event "+app.index_event, ...
                "NodeData",{app.DurationmsecDropDown.Items ,app.EnterValueinmsecEditField.Value}, ...
                'ContextMenu',app.ContextMenu2,'Tag','event' ...
                ); 
            else
            uitreenode(app.Tree,'Text',"Event "+app.index_event, ...
                "NodeData",{app.DurationmsecDropDown.Items ,app.EnterValueinmsecEditField.Value}, ...
                'ContextMenu',app.ContextMenu2,'Tag','event');
            end
            
        end

        % Value changed function: ChooseDropDown
        function ChooseDropDownValueChanged(app, event)
            value = app.ChooseDropDown.Value;
            switch value
                case 'White Noise'
                    app.BWHzEditField.Enable = 'off';
                    app.cntrfreqHzEditField.Enable = 'off';
                case 'NB Noise'
                    app.BWHzEditField.Enable = 'on';
                    app.cntrfreqHzEditField.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.Label = uilabel(app.IntroTab);
            app.Label.Text = {'Welcome to the experiment generator, a tool that allows you to create customized experiments with different inputs and speakers. To use this tool, you need to follow these steps:'; ''; 'In the first tab (where you read this), you will see an intro that explains the purpose and the features of the tool. You can skip this tab if you are familiar with the tool.'; 'In the second tab, you will see a configuration panel where you can select the inputs and the speakers for your experiment. You can choose from a list of predefined inputs or use your own wav audio files. You can also choose from a list of available speakers or record your own voice. You can set complex expreiemtn or select random speakers. '; 'In the third tab, you will see a setting panel where you can name your output file and choose where to save it. You can also review your selections and make any changes if needed. Once you are satisfied with your settings, click on Generate to create your experiment file.'; 'That’s it! You have successfully created your experiment file using the experiment generator. You can now use it for your research or analysis purposes. Thank you for using this tool!'};
            app.BeginButton = uibutton(app.IntroTab, 'push');
            app.BeginButton.Text = 'Begin';
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.GenerateDropDownLabel = uilabel(app.ConfigurationTab);
            app.GenerateDropDownLabel.Text = 'Generate';
            app.GenerateDropDown = uidropdown(app.ConfigurationTab);
            app.GenerateDropDown.Items = {'None', 'Pure Tone', 'Noise', 'File'};
            app.GenerateDropDown.ValueChangedFcn = createCallbackFcn(app, @GenerateDropDownValueChanged, true);
            app.GenerateDropDown.Value = 'None';
            app.Tree = uitree(app.ConfigurationTab);
            app.Event1Node = uitreenode(app.Tree);
            app.Event1Node.Tag = 'event';
            app.Event1Node.Text = 'Event 1';
            app.NewEventButton = uibutton(app.ConfigurationTab, 'push');
            app.NewEventButton.ButtonPushedFcn = createCallbackFcn(app, @NewEventButtonPushed, true);
            app.NewEventButton.Text = 'New Event';
            app.continuetofinishButton = uibutton(app.ConfigurationTab, 'push');
            app.continuetofinishButton.Text = 'continue to finish';
            app.AddtoEventButton = uibutton(app.ConfigurationTab, 'push');
            app.AddtoEventButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoEventButtonPushed, true);
            app.AddtoEventButton.Enable = 'off';
            app.AddtoEventButton.Text = 'Add to Event';
            app.PanelSignalPureTone = uipanel(app.ConfigurationTab);
            app.PanelSignalPureTone.Enable = 'off';
            app.PanelSignalPureTone.Title = 'Panel Signal Pure Tone';
            app.PanelSignalPureTone.Visible = 'off';
            app.PhaseDropDownLabel = uilabel(app.PanelSignalPureTone);
            app.PhaseDropDownLabel.Text = 'Phase';
            app.PhaseDropDown = uidropdown(app.PanelSignalPureTone);
            app.PhaseDropDown.Items = {'0', '90', '180', '270'};
            app.PhaseDropDown.Value = '0';
            app.FrequencyHzDropDownLabel = uilabel(app.PanelSignalPureTone);
            app.FrequencyHzDropDownLabel.Text = 'Frequency(Hz)';
            app.FrequencyHzDropDown = uidropdown(app.PanelSignalPureTone);
            app.FrequencyHzDropDown.Items = {'250', '500', '1000', '2000'};
            app.FrequencyHzDropDown.Value = '1000';
            app.LeveldBEditFieldLabel = uilabel(app.PanelSignalPureTone);
            app.LeveldBEditFieldLabel.Text = 'Level (dB)';
            app.LeveldBEditField = uieditfield(app.PanelSignalPureTone, 'numeric');
            app.LeveldBEditField.Value = 35;
            app.onsetmsecEditFieldLabel = uilabel(app.PanelSignalPureTone);
            app.onsetmsecEditFieldLabel.Text = 'onset (msec)';
            app.onsetmsecEditField = uieditfield(app.PanelSignalPureTone, 'numeric');
            app.offsetmsecEditFieldLabel = uilabel(app.PanelSignalPureTone);
            app.offsetmsecEditFieldLabel.Text = 'offset (msec)';
            app.offsetmsecEditField = uieditfield(app.PanelSignalPureTone, 'numeric');
            app.ModulationPanel = uipanel(app.ConfigurationTab);
            app.ModulationPanel.Enable = 'off';
            app.ModulationPanel.Title = 'Modulation';
            app.ModulationPanel.Visible = 'off';
            app.FMModulationCheckBox = uicheckbox(app.ModulationPanel);
            app.FMModulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @FMModulationCheckBoxValueChanged, true);
            app.FMModulationCheckBox.Text = 'FM Modulation';
            app.ModulationIndexEditFieldLabel = uilabel(app.ModulationPanel);
            app.ModulationIndexEditFieldLabel.Text = {'Modulation'; 'Index'};
            app.ModulationIndexEditField = uieditfield(app.ModulationPanel, 'numeric');
            app.ModulationIndexEditField.Enable = 'off';
            app.AMModulationCheckBox = uicheckbox(app.ModulationPanel);
            app.AMModulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AMModulationCheckBoxValueChanged, true);
            app.AMModulationCheckBox.Text = 'AM Modulation';
            app.depth0100EditFieldLabel = uilabel(app.ModulationPanel);
            app.depth0100EditFieldLabel.Text = {'depth'; '0-100%'};
            app.depth0100EditField = uieditfield(app.ModulationPanel, 'numeric');
            app.depth0100EditField.Limits = [0 100];
            app.depth0100EditField.Enable = 'off';
            app.ModFreqHzEditFieldLabel = uilabel(app.ModulationPanel);
            app.ModFreqHzEditFieldLabel.Text = 'Mod. Freq (Hz)';
            app.ModFreqHzEditField = uieditfield(app.ModulationPanel, 'numeric');
            app.DurationmsecDropDownLabel = uilabel(app.ConfigurationTab);
            app.DurationmsecDropDownLabel.Text = 'Duration (msec)';
            app.DurationmsecDropDown = uidropdown(app.ConfigurationTab);
            app.DurationmsecDropDown.Items = {'None', 'value'};
            app.DurationmsecDropDown.Value = 'value';
            app.EnterValueinmsecEditFieldLabel = uilabel(app.ConfigurationTab);
            app.EnterValueinmsecEditFieldLabel.Text = 'Enter Value in msec';
            app.EnterValueinmsecEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.EnterValueinmsecEditField.Limits = [0 Inf];
            app.EnterValueinmsecEditField.Value = 3;
            app.OutputSelectionPanel = uipanel(app.ConfigurationTab);
            app.OutputSelectionPanel.Title = 'Output Selection';
            app.TXFF1 = uicheckbox(app.OutputSelectionPanel);
            app.TXFF1.Text = '';
            app.TXFF2 = uicheckbox(app.OutputSelectionPanel);
            app.TXFF2.Text = '';
            app.TXFF3 = uicheckbox(app.OutputSelectionPanel);
            app.TXFF3.Text = '';
            app.TXFF4 = uicheckbox(app.OutputSelectionPanel);
            app.TXFF4.Text = '';
            app.TXFF8 = uicheckbox(app.OutputSelectionPanel);
            app.TXFF8.Text = '';
            app.TXFF7 = uicheckbox(app.OutputSelectionPanel);
            app.TXFF7.Text = '';
            app.TXFF6 = uicheckbox(app.OutputSelectionPanel);
            app.TXFF6.Text = '';
            app.TXFF5 = uicheckbox(app.OutputSelectionPanel);
            app.TXFF5.Text = '';
            app.Label_2 = uilabel(app.OutputSelectionPanel);
            app.Label_2.Text = '1';
            app.Label_3 = uilabel(app.OutputSelectionPanel);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.OutputSelectionPanel);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.OutputSelectionPanel);
            app.Label_5.Text = '3';
            app.Label_6 = uilabel(app.OutputSelectionPanel);
            app.Label_6.Text = '8';
            app.Label_7 = uilabel(app.OutputSelectionPanel);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.OutputSelectionPanel);
            app.Label_8.Text = '6';
            app.Label_9 = uilabel(app.OutputSelectionPanel);
            app.Label_9.Text = '5';
            app.ActivateRandomSpeakersCheckBox = uicheckbox(app.ConfigurationTab);
            app.ActivateRandomSpeakersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivateRandomSpeakersCheckBoxValueChanged, true);
            app.ActivateRandomSpeakersCheckBox.Tooltip = {'From Selected Speakers, select X randomly'};
            app.ActivateRandomSpeakersCheckBox.Text = 'Activate Random Speakers';
            app.HowmanyspeakerstoselectrandomEditFieldLabel = uilabel(app.ConfigurationTab);
            app.HowmanyspeakerstoselectrandomEditFieldLabel.Enable = 'off';
            app.HowmanyspeakerstoselectrandomEditFieldLabel.Text = 'How many speakers to select random';
            app.HowmanyspeakerstoselectrandomEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.HowmanyspeakerstoselectrandomEditField.ValueChangedFcn = createCallbackFcn(app, @HowmanyspeakerstoselectrandomEditFieldValueChanged, true);
            app.HowmanyspeakerstoselectrandomEditField.Enable = 'off';
            app.PanelNoise = uipanel(app.ConfigurationTab);
            app.PanelNoise.Title = 'Panel Noise';
            app.ChooseDropDownLabel = uilabel(app.PanelNoise);
            app.ChooseDropDownLabel.Text = 'Choose';
            app.ChooseDropDown = uidropdown(app.PanelNoise);
            app.ChooseDropDown.Items = {'White Noise', 'NB Noise'};
            app.ChooseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseDropDownValueChanged, true);
            app.ChooseDropDown.Value = 'White Noise';
            app.phasedegDropDownLabel = uilabel(app.PanelNoise);
            app.phasedegDropDownLabel.Text = 'phase (deg)';
            app.phasedegDropDown = uidropdown(app.PanelNoise);
            app.phasedegDropDown.Items = {'0', '180'};
            app.phasedegDropDown.Value = '0';
            app.LeveldBEditField_2Label = uilabel(app.PanelNoise);
            app.LeveldBEditField_2Label.Text = 'Level (dB)';
            app.LeveldBEditField_2 = uieditfield(app.PanelNoise, 'numeric');
            app.cntrfreqHzEditFieldLabel = uilabel(app.PanelNoise);
            app.cntrfreqHzEditFieldLabel.Text = 'cntr freq (Hz)';
            app.cntrfreqHzEditField = uieditfield(app.PanelNoise, 'numeric');
            app.cntrfreqHzEditField.Enable = 'off';
            app.cntrfreqHzEditField.Value = 1000;
            app.BWHzEditFieldLabel = uilabel(app.PanelNoise);
            app.BWHzEditFieldLabel.Text = 'BW (Hz)';
            app.BWHzEditField = uieditfield(app.PanelNoise, 'numeric');
            app.BWHzEditField.Enable = 'off';
            app.BWHzEditField.Value = 100;
            app.FinishTab = uitab(app.TabGroup);
            app.FinishTab.Title = 'Finish';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.OpenMenu = uimenu(app.ContextMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu2
            app.Event1Node.ContextMenu = app.ContextMenu2;
            app.DeleteMenu_2 = uimenu(app.ContextMenu2);
            app.DeleteMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu_2.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExpreimentGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end