classdef NoiseOptionsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        NoiseOptionsLabel         matlab.ui.control.Label
        TXNumberTitleLabel        matlab.ui.control.Label
        TXNumberLabel             matlab.ui.control.Label
        amplitudeEditField        matlab.ui.control.NumericEditField
        amplitudeSlider           matlab.ui.control.Slider
        amplitudedBSPLLabel       matlab.ui.control.Label
        phasedegDropDownLabel     matlab.ui.control.Label
        phasedegDropDown          matlab.ui.control.DropDown
        ChooseDropDownLabel       matlab.ui.control.Label
        ChooseDropDown            matlab.ui.control.DropDown
        NBNoiseOptionsPanel       matlab.ui.container.Panel
        cntrfreqHzEditFieldLabel  matlab.ui.control.Label
        cntrfreqHzEditField       matlab.ui.control.NumericEditField
        BWHzEditFieldLabel        matlab.ui.control.Label
        BWHzEditField             matlab.ui.control.NumericEditField
        PleasekeepthewindowopeninorderthesettingswilltakeeffectLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        TXNumberString 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, TXNumberDouble)
                app.TXNumberLabel.Text = num2str(TXNumberDouble);
                app.TXNumberString = strcat("TX",num2str(TXNumberDouble));
                
                load mdb;
                app.amplitudeEditField.Value = mdb.(app.TXNumberString).stimulus.noise.amp;
                app.amplitudeSlider.Value = app.amplitudeEditField.Value;
                idx = find(cellfun(@(x) str2num(x)==mdb.(app.TXNumberString).stimulus.noise.phase, app.phasedegDropDown.Items));
                if(~isempty(idx))
                   app.phasedegDropDown.Value = num2str(mdb.(app.TXNumberString).stimulus.noise.phase); 
                end
                if(mdb.(app.TXNumberString).stimulus.noise.source==2)
%                     idx = mdb.(app.TXNumberString).stimulus.noise.source;
                    app.ChooseDropDown.Value = app.ChooseDropDown.Items{2};
                    app.cntrfreqHzEditField.Enable = 1;
                    app.BWHzEditField.Enable = 1;
                end
                app.cntrfreqHzEditField.Value = mdb.(app.TXNumberString).stimulus.noise.NBCntrFrq;
                app.BWHzEditField.Value = mdb.(app.TXNumberString).stimulus.noise.NBBW;


        end

        % Value changed function: amplitudeSlider
        function amplitudeSliderValueChanged(app, event)
            value = app.amplitudeSlider.Value;
            app.amplitudeEditField.Value = value;
%             load mdb;
%             mdb.(app.TXNumberString).stimulus.noise.amp = value;
%             save mdb mdb;
            
        end

        % Value changed function: amplitudeEditField
        function amplitudeEditFieldValueChanged(app, event)
            value = app.amplitudeEditField.Value;
            app.amplitudeSlider.Value = value;
%             load mdb;
%             mdb.(app.TXNumberString).stimulus.noise.amp = value;
%             save mdb mdb;
        end

        % Value changed function: phasedegDropDown
        function phasedegDropDownValueChanged(app, event)
%             load mdb;
%             value = app.phasedegDropDown.Value;
%             mdb.(app.TXNumberString).stimulus.noise.phase = str2num(value);
%             save mdb mdb;
        end

        % Value changed function: ChooseDropDown
        function ChooseDropDownValueChanged(app, event)
            value = app.ChooseDropDown.Value;
            TXNumber = app.TXNumberString;
            
            % Set current data to the selected data set.
            load mdb;
%             set(handles.stimulusNoiseSourceFileName_staticText,'Visible','off'); %reset the source file name
%             set(handles.stimulus_noiseFilter_pannel,'Visible','off');
            app.BWHzEditField.Enable = 0;
            app.cntrfreqHzEditField.Enable = 0;
            
          switch value %TODO fix this code 
              case 'White Noise'
%                     mdb.(TXNumber).stimulus.noise.source = 1; %1=white noise'
              case 'NB Noise'
%                     set(handles.stimulus_noiseFilter_pannel,'Visible','on');
                        app.BWHzEditField.Enable = 1;
                        app.cntrfreqHzEditField.Enable = 1;            
%                         mdb.(TXNumber).stimulus.noise.source = 2;  %2=NB noise
              case 'From File'
%                      [FileName,PathName,FilterIndex] = uigetfile('D:\Users\students\*.wav');
%                      fullfilename = sprintf('%s%s',PathName,FileName);
%                      disp(fullfilename);
%                      mdb.(TXNumber).stimulus.noise.source = 3;  %3=from file
%                       mdb.(TXNumber).stimulus.noise.fileName = fullfilename;
%                     set(handles.stimulusNoiseSourceFileName_staticText,'String',strcat('File Name: "',FileName,'"'));  
%                     set(handles.stimulusNoiseSourceFileName_staticText,'Visible','on');        
            end
            
             save mdb mdb;
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NoiseOptionsLabel = uilabel(app.UIFigure);
            app.NoiseOptionsLabel.Text = 'Noise Options';
            app.TXNumberTitleLabel = uilabel(app.UIFigure);
            app.TXNumberTitleLabel.Text = 'TX Number:';
            app.TXNumberLabel = uilabel(app.UIFigure);
            app.TXNumberLabel.Text = '';
            app.amplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.amplitudeEditField.Limits = [-75 125];
            app.amplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @amplitudeEditFieldValueChanged, true);
            app.amplitudeEditField.Value = 35;
            app.amplitudeSlider = uislider(app.UIFigure);
            app.amplitudeSlider.Limits = [-75 125];
            app.amplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @amplitudeSliderValueChanged, true);
            app.amplitudeSlider.Value = 35;
            app.amplitudedBSPLLabel = uilabel(app.UIFigure);
            app.amplitudedBSPLLabel.Text = 'amplitude (dBSPL)';
            app.phasedegDropDownLabel = uilabel(app.UIFigure);
            app.phasedegDropDownLabel.Text = 'phase (deg)';
            app.phasedegDropDown = uidropdown(app.UIFigure);
            app.phasedegDropDown.Items = {'0', '180'};
            app.phasedegDropDown.ValueChangedFcn = createCallbackFcn(app, @phasedegDropDownValueChanged, true);
            app.phasedegDropDown.Value = '0';
            app.ChooseDropDownLabel = uilabel(app.UIFigure);
            app.ChooseDropDownLabel.Text = 'Choose';
            app.ChooseDropDown = uidropdown(app.UIFigure);
            app.ChooseDropDown.Items = {'White Noise', 'NB Noise'};
            app.ChooseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseDropDownValueChanged, true);
            app.ChooseDropDown.Value = 'White Noise';
            app.NBNoiseOptionsPanel = uipanel(app.UIFigure);
            app.NBNoiseOptionsPanel.Title = 'NB Noise Options';
            app.cntrfreqHzEditFieldLabel = uilabel(app.NBNoiseOptionsPanel);
            app.cntrfreqHzEditFieldLabel.Text = 'cntr freq (Hz)';
            app.cntrfreqHzEditField = uieditfield(app.NBNoiseOptionsPanel, 'numeric');
            app.cntrfreqHzEditField.Enable = 'off';
            app.cntrfreqHzEditField.Value = 1000;
            app.BWHzEditFieldLabel = uilabel(app.NBNoiseOptionsPanel);
            app.BWHzEditFieldLabel.Text = 'BW (Hz)';
            app.BWHzEditField = uieditfield(app.NBNoiseOptionsPanel, 'numeric');
            app.BWHzEditField.Enable = 'off';
            app.BWHzEditField.Value = 100;
            app.PleasekeepthewindowopeninorderthesettingswilltakeeffectLabel = uilabel(app.UIFigure);
            app.PleasekeepthewindowopeninorderthesettingswilltakeeffectLabel.Text = 'Please keep the window open in order the settings will take effect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NoiseOptionsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end