classdef PureTuneOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PureTuneOptionsLabel           matlab.ui.control.Label
        TXNumberTitleLabel             matlab.ui.control.Label
        TXNumberLabel                  matlab.ui.control.Label
        freqSlider                     matlab.ui.control.Slider
        freqEditField                  matlab.ui.control.NumericEditField
        frequencyHzLabel               matlab.ui.control.Label
        amplitudeEditField             matlab.ui.control.NumericEditField
        amplitudeSlider                matlab.ui.control.Slider
        amplitudedBSPLLabel            matlab.ui.control.Label
        phasedegDropDownLabel          matlab.ui.control.Label
        phasedegDropDown               matlab.ui.control.DropDown
        ModulationPanel                matlab.ui.container.Panel
        FMModulationCheckBox           matlab.ui.control.CheckBox
        ModulationIndexEditFieldLabel  matlab.ui.control.Label
        ModulationIndexEditField       matlab.ui.control.NumericEditField
        AMModulationCheckBox           matlab.ui.control.CheckBox
        depth0100EditFieldLabel        matlab.ui.control.Label
        depth0100EditField             matlab.ui.control.NumericEditField
        ModFreqHzEditFieldLabel        matlab.ui.control.Label
        ModFreqHzEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        TXNumberString 
    end
    
    methods (Access = private)
        
%         function [FM,AM] = ModulationTypeToFmAmModes(~,modulationType)
%             switch(modulationType)
%                 case 0
%                     FM = 0;
%                     AM = 1;
%                 case 1
%                     FM = 1;
%                     AM = 0;
%                 case 2
%                     FM = 1;
%                     AM = 1;
%                 case 3
%                     FM = 0;
%                     AM = 0;
%             end
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, TXNumberDouble)
            app.TXNumberLabel.Text = num2str(TXNumberDouble);
            app.TXNumberString = strcat("TX",num2str(TXNumberDouble));
            
            
            load mdb;
            app.amplitudeEditField.Value = mdb.(app.TXNumberString).stimulus.PT.amp;
            app.amplitudeSlider.Value = app.amplitudeEditField.Value;
            
            app.freqEditField.Value  = mdb.(app.TXNumberString).stimulus.PT.freq;
            app.freqSlider.Value = app.freqEditField.Value;
            
            idx = find(cellfun(@(x) str2double(x)==mdb.(app.TXNumberString).stimulus.PT.phase, app.phasedegDropDown.Items),1);
            if(~isempty(idx))
               app.phasedegDropDown.Value = app.phasedegDropDown.Items{idx}; 
            end
            app.ModFreqHzEditField.Value = mdb.(app.TXNumberString).stimulus.PT.modFreq;
            app.depth0100EditField.Value = mdb.(app.TXNumberString).stimulus.PT.modDepth;
            [FM,AM] = ModulationTypeToFmAmModes(mdb.(app.TXNumberString).stimulus.PT.modulationType);
            if(AM)
                app.AMModulationCheckBox.Value = 1;
                app.depth0100EditField.Enable = 1;
            end
            if(FM)
                app.FMModulationCheckBox.Value = 1;
                app.ModulationIndexEditField.Enable = 1;
            end
            
            
        end

        % Value changed function: freqEditField
        function freqEditFieldValueChanged(app, event)
            value = app.freqEditField.Value;
            if(ismember(value,[250,500,1000,2000,4000]))    
                TXNumber = app.TXNumberString;
                load mdb;
                mdb.(TXNumber).stimulus.PT.freq = value;
                app.freqSlider.Value = value;
                save mdb mdb; 
            else 
                uialert(app.UIFigure,"You can use only one of the next values: [250,500,1000,2000,4000]","Illegel Freq");
            end
        end

        % Value changed function: freqSlider
        function freqSliderValueChanged(app, event)
            value = app.freqSlider.Value;
            app.freqEditField.Value = value;
            load mdb;
            mdb.(app.TXNumberString).stimulus.PT.freq = value;
            save mdb mdb;
            
        end

        % Value changed function: amplitudeSlider
        function amplitudeSliderValueChanged(app, event)
            value = app.amplitudeSlider.Value;
            app.amplitudeEditField.Value = value;
            load mdb;
            mdb.(app.TXNumberString).stimulus.PT.amp = value;
            save mdb mdb;
            
        end

        % Value changed function: amplitudeEditField
        function amplitudeEditFieldValueChanged(app, event)
            value = app.amplitudeEditField.Value;
            app.amplitudeSlider.Value = value;
            load mdb;
            mdb.(app.TXNumberString).stimulus.PT.amp = value;
            save mdb mdb;
        end

        % Value changed function: phasedegDropDown
        function phasedegDropDownValueChanged(app, event)
            value = app.phasedegDropDown.Value;
            load mdb
            mdb.(app.TXNumberString).stimulus.PT.phase = str2num(value);
            save mdb mdb
        end

        % Value changed function: FMModulationCheckBox
        function FMModulationCheckBoxValueChanged(app, event)
            value = app.FMModulationCheckBox.Value;
            app.ModulationIndexEditField.Enable = value;
            load mdb;
            mdb.(app.TXNumberString).stimulus.PT.modulationType = PTModulationModeHelper(app.FMModulationCheckBox.Value,app.AMModulationCheckBox.Value);
            save mdb mdb;
        end

        % Value changed function: ModulationIndexEditField
        function ModulationIndexEditFieldValueChanged(app, event)
            value = app.ModulationIndexEditField.Value;
            
            load mdb;
            mdb.(app.TXNumberString).stimulus.PT.modIndex = value;
            save mdb mdb;
        end

        % Value changed function: AMModulationCheckBox
        function AMModulationCheckBoxValueChanged(app, event)
            value = app.AMModulationCheckBox.Value;
            app.depth0100EditField.Enable = value;
%             PTModulationModeHelper(app.TXNumberString,app.FMModulationCheckBox.Value,app.AMModulationCheckBox.Value);
            load mdb;
            mdb.(app.TXNumberString).stimulus.PT.modulationType = PTModulationModeHelper(app.FMModulationCheckBox.Value,app.AMModulationCheckBox.Value);
            save mdb mdb;
            
        end

        % Value changed function: depth0100EditField
        function depth0100EditFieldValueChanged(app, event)
            value = app.depth0100EditField.Value;
            load mdb;
            mdb.(app.TXNumberString).stimulus.PT.modDepth = value;
            save mdb mdb;
        end

        % Value changed function: ModFreqHzEditField
        function ModFreqHzEditFieldValueChanged(app, event)
            value = app.ModFreqHzEditField.Value;
             load mdb;
             mdb.(app.TXNumberString).stimulus.PT.modFreq = value;
             save mdb mdb;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PureTuneOptionsLabel = uilabel(app.UIFigure);
            app.PureTuneOptionsLabel.Text = 'Pure Tune Options';
            app.TXNumberTitleLabel = uilabel(app.UIFigure);
            app.TXNumberTitleLabel.Text = 'TX Number:';
            app.TXNumberLabel = uilabel(app.UIFigure);
            app.TXNumberLabel.Text = '';
            app.freqSlider = uislider(app.UIFigure);
            app.freqSlider.Limits = [200 4000];
            app.freqSlider.MajorTicks = [250 500 1000 2000 4000];
            app.freqSlider.MajorTickLabels = {'250', '500', '1000', '2000', '4000'};
            app.freqSlider.ValueChangedFcn = createCallbackFcn(app, @freqSliderValueChanged, true);
            app.freqSlider.MinorTicks = [];
            app.freqSlider.Enable = 'off';
            app.freqSlider.Value = 1000;
            app.freqEditField = uieditfield(app.UIFigure, 'numeric');
            app.freqEditField.Limits = [200 8200];
            app.freqEditField.ValueChangedFcn = createCallbackFcn(app, @freqEditFieldValueChanged, true);
            app.freqEditField.Value = 1000;
            app.frequencyHzLabel = uilabel(app.UIFigure);
            app.frequencyHzLabel.Text = 'frequency (Hz)';
            app.amplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.amplitudeEditField.Limits = [-75 125];
            app.amplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @amplitudeEditFieldValueChanged, true);
            app.amplitudeEditField.Value = 35;
            app.amplitudeSlider = uislider(app.UIFigure);
            app.amplitudeSlider.Limits = [-75 125];
            app.amplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @amplitudeSliderValueChanged, true);
            app.amplitudeSlider.Value = 35;
            app.amplitudedBSPLLabel = uilabel(app.UIFigure);
            app.amplitudedBSPLLabel.Text = 'amplitude (dBSPL)';
            app.phasedegDropDownLabel = uilabel(app.UIFigure);
            app.phasedegDropDownLabel.Text = 'phase (deg)';
            app.phasedegDropDown = uidropdown(app.UIFigure);
            app.phasedegDropDown.Items = {'0', '90', '180', '270'};
            app.phasedegDropDown.ValueChangedFcn = createCallbackFcn(app, @phasedegDropDownValueChanged, true);
            app.phasedegDropDown.Value = '0';
            app.ModulationPanel = uipanel(app.UIFigure);
            app.ModulationPanel.Title = 'Modulation';
            app.FMModulationCheckBox = uicheckbox(app.ModulationPanel);
            app.FMModulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @FMModulationCheckBoxValueChanged, true);
            app.FMModulationCheckBox.Text = 'FM Modulation';
            app.ModulationIndexEditFieldLabel = uilabel(app.ModulationPanel);
            app.ModulationIndexEditFieldLabel.Text = {'Modulation'; 'Index'};
            app.ModulationIndexEditField = uieditfield(app.ModulationPanel, 'numeric');
            app.ModulationIndexEditField.ValueChangedFcn = createCallbackFcn(app, @ModulationIndexEditFieldValueChanged, true);
            app.ModulationIndexEditField.Enable = 'off';
            app.AMModulationCheckBox = uicheckbox(app.ModulationPanel);
            app.AMModulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AMModulationCheckBoxValueChanged, true);
            app.AMModulationCheckBox.Text = 'AM Modulation';
            app.depth0100EditFieldLabel = uilabel(app.ModulationPanel);
            app.depth0100EditFieldLabel.Text = {'depth'; '0-100%'};
            app.depth0100EditField = uieditfield(app.ModulationPanel, 'numeric');
            app.depth0100EditField.Limits = [0 100];
            app.depth0100EditField.ValueChangedFcn = createCallbackFcn(app, @depth0100EditFieldValueChanged, true);
            app.depth0100EditField.Enable = 'off';
            app.ModFreqHzEditFieldLabel = uilabel(app.ModulationPanel);
            app.ModFreqHzEditFieldLabel.Text = 'Mod. Freq (Hz)';
            app.ModFreqHzEditField = uieditfield(app.ModulationPanel, 'numeric');
            app.ModFreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @ModFreqHzEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PureTuneOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end