classdef TDT_GUI_v2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure         matlab.ui.Figure
        Main               matlab.ui.container.Menu
        Menu               matlab.ui.container.Menu
        Output1Panel       matlab.ui.container.Panel
        TX1FF1             matlab.ui.control.CheckBox
        TX1FF2             matlab.ui.control.CheckBox
        TX1FF3             matlab.ui.control.CheckBox
        TX1FF4             matlab.ui.control.CheckBox
        TX1FF5             matlab.ui.control.CheckBox
        TX1FF6             matlab.ui.control.CheckBox
        TX1FF7             matlab.ui.control.CheckBox
        TX1FF8             matlab.ui.control.CheckBox
        TX1Properties      matlab.ui.control.Label
        TX1_title          matlab.ui.container.Panel
        OptionsButton1     matlab.ui.control.Button
        TX1                matlab.ui.control.DropDown
        startPauseButton   matlab.ui.control.StateButton
        uiSpeakerPanels    matlab.ui.container.Panel
        speakerText1       matlab.ui.control.Label
        speakerText2       matlab.ui.control.Label
        speakerText3       matlab.ui.control.Label
        speakerText4       matlab.ui.control.Label
        speakerText5       matlab.ui.control.Label
        speakerText6       matlab.ui.control.Label
        speakerText7       matlab.ui.control.Label
        speakerText8       matlab.ui.control.Label
        SpeakersMapButton  matlab.ui.control.Button
        mapSpeakers        matlab.ui.control.Button
        TX2_title          matlab.ui.container.Panel
        OptionsButton2     matlab.ui.control.Button
        TX2                matlab.ui.control.DropDown
        Output2Panel       matlab.ui.container.Panel
        TX2FF1             matlab.ui.control.CheckBox
        TX2FF2             matlab.ui.control.CheckBox
        TX2FF3             matlab.ui.control.CheckBox
        TX2FF4             matlab.ui.control.CheckBox
        TX2FF5             matlab.ui.control.CheckBox
        TX2FF6             matlab.ui.control.CheckBox
        TX2FF7             matlab.ui.control.CheckBox
        TX2FF8             matlab.ui.control.CheckBox
        TX2Properties      matlab.ui.control.Label
    end

    
    methods (Access = private)
        function TX1_AmpValue_editText_Callback(app, hObject, eventdata, handles)
            % hObject    handle to TX1_AmpValue_editText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            user_entry = str2double(get(hObject,'string'));
            if isnan(user_entry)
                errordlg('You must enter a numeric value','Bad Input','modal')
                uicontrol(hObject)
                return
            end
            
            load mdb;
            %TODO: add support for noise, don't forget to change the mdb right flags
            %based on the choise option in input Signals. need to check if it has
            %effect.
            % TODO: Add Options support
            %check if entered value is in frequency range
            sliderMax = get(mdb.TX1.stimulus.PT.PTAmpSelectSlider_handle,'Max');
            sliderMin = get(mdb.TX1.stimulus.PT.PTAmpSelectSlider_handle,'Min');
            if (user_entry <  sliderMin)
                set(hObject,'String',sliderMin);
                set(mdb.TX1.stimulus.PT.PTAmpSelectSlider_handle,'Value',sliderMin);
                mdb.TX1.stimulus.PT.amp = sliderMin;
            elseif (user_entry > sliderMax )
                set(hObject,'String',sliderMax);
                set(mdb.TX1.stimulus.PT.PTAmpSelectSlider_handle,'Value',sliderMax);
                mdb.TX1.stimulus.PT.amp = sliderMax;
            else
            set(mdb.TX1.stimulus.PT.PTAmpSelectSlider_handle,'Value',user_entry);
             mdb.TX1.stimulus.PT.amp = user_entry;
            
            end
            save mdb mdb;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function TDT_GUI_v2_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to TDT_GUI_v2 (see VARARGIN)
            
            % Choose default command line output for TDT_GUI_v2
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: OptionsButton1
        function OptionsButton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to OptionsButton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % load parameters
            % if(0 == parameters.Options.SingleTon)
            %     parameters.Options.TxNumber = 1;
            %     save parameters
            
               SignalChoices = get(handles.TX1,'String');
               switch SignalChoices{get(handles.TX1,'Value')}
                   case 'Tune'
                     PTOptions(1);
                   case 'Noise'
                     NoiseOptions(1);
                   case 'Speech'
                     SpeechOptions(1);
               end
        end

        % Button pushed function: OptionsButton2
        function OptionsButton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to OptionsButton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
               SignalChoices = get(handles.TX2,'String');
               switch SignalChoices{get(handles.TX2,'Value')}
                   case 'Tune'
                     PTOptions(2);
                   case 'Noise'
                     NoiseOptions(2);
                   case 'Speech'
                     SpeechOptions(2);
               end
        end

        % Button pushed function: SpeakersMapButton
        function SpeakersMapButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SpeakersMapButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            I = imread('Maps/PP16Map.jpg');
            figure();
            imshow(I);
        end

        % Value changed function: TX1FF1
        function TX1FF1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                load mdb;
                mdb.TX1.transducer.FF.DacVector(1) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX1FF2
        function TX1FF2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX1FF2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX1FF2
                load mdb;
                mdb.TX1.transducer.FF.DacVector(2) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX1FF3
        function TX1FF3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX1FF3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX1FF3
                load mdb;
                mdb.TX1.transducer.FF.DacVector(3) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX1FF4
        function TX1FF4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX1FF4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX1FF4
                load mdb;
                mdb.TX1.transducer.FF.DacVector(4) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX1FF5
        function TX1FF5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX1FF5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX1FF5
                load mdb;
                mdb.TX1.transducer.FF.DacVector(5) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX1FF6
        function TX1FF6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX1FF6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX1FF6
                load mdb;
                mdb.TX1.transducer.FF.DacVector(6) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX1FF7
        function TX1FF7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX1FF7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX1FF7
                load mdb;
                mdb.TX1.transducer.FF.DacVector(7) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX1FF8
        function TX1FF8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX1FF8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX1FF8
                load mdb;
                mdb.TX1.transducer.FF.DacVector(8) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX1
        function TX1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            load mdb;
            % reset mdb.TX1.stimulus.stimulusSelect struct to 0
            fieldNames = fieldnames(mdb.TX1.stimulus.stimulusSelect);
            for ii=1:length(fieldNames)
                mdb.TX1.stimulus.stimulusSelect.(fieldNames{ii}) = 0;
            end
            mdb.TX1.transducer.source = 'FF';
            
            
            contents = get(hObject,'String');
            value = contents{get(hObject,'Value')};
            
            if(~strcmp(value,'None'))
                set(handles.Output1Panel,'Visible','On');
                set(handles.OptionsButton1,'Visible','On');
                mdb.master.TX1_select = 1;
            else
                set(handles.Output1Panel,'Visible','Off');
                set(handles.OptionsButton1,'Visible','Off');
                mdb.master.TX1_select = 0;
            end
            
            if (strcmp(value,'Tune'))
                mdb.TX1.stimulus.stimulusSelect.pureTone = 1;
            %     set(handles.TX1_AmpSelect_slider,'Max',150);
            %     set(handles.TX1_AmpSelect_slider,'Min',-75);
            
            elseif(strcmp(value,'Noise'))
                mdb.TX1.stimulus.stimulusSelect.noise = 1;
            %     set(handles.TX1_AmpSelect_slider,'Max',125);
            %     set(handles.TX1_AmpSelect_slider,'Min',0);
            elseif(strcmp(value,'Speech'))
                mdb.TX1.stimulus.stimulusSelect.speech = 1;
            
            end
            save mdb mdb;
        end

        % Value changed function: TX2FF1
        function TX2FF1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX2FF1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX2FF1
                load mdb;
                mdb.TX2.transducer.FF.DacVector(1) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX2FF2
        function TX2FF2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX2FF2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX2FF2
                load mdb;
                mdb.TX2.transducer.FF.DacVector(2) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX2FF3
        function TX2FF3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX2FF3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX2FF3
                load mdb;
                mdb.TX2.transducer.FF.DacVector(3) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX2FF4
        function TX2FF4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX2FF4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX2FF4
                load mdb;
                mdb.TX2.transducer.FF.DacVector(4) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX2FF5
        function TX2FF5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX2FF5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX2FF5
                load mdb;
                mdb.TX2.transducer.FF.DacVector(5) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX2FF6
        function TX2FF6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX2FF6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX2FF6
                load mdb;
                mdb.TX2.transducer.FF.DacVector(6) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX2FF7
        function TX2FF7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX2FF7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX2FF7
                load mdb;
                mdb.TX2.transducer.FF.DacVector(7) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX2FF8
        function TX2FF8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX2FF8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TX2FF8
                load mdb;
                mdb.TX2.transducer.FF.DacVector(8) =  get(hObject,'Value');
                save mdb mdb ;
        end

        % Value changed function: TX2
        function TX2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TX2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns TX2 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from TX2
            
            load mdb;
            % reset mdb.TX1.stimulus.stimulusSelect struct to 0
            fieldNames = fieldnames(mdb.TX2.stimulus.stimulusSelect);
            for ii=1:length(fieldNames)
                mdb.TX2.stimulus.stimulusSelect.(fieldNames{ii}) = 0;
            end
            mdb.TX2.transducer.source = 'FF';
            
            
            contents = get(hObject,'String');
            value = contents{get(hObject,'Value')};
            
            if(~strcmp(value,'None'))
                set(handles.Output2Panel,'Visible','On');
                set(handles.OptionsButton2,'Visible','On');
                mdb.master.TX2_select = 1;
            else
                set(handles.Output2Panel,'Visible','Off');
                set(handles.OptionsButton2,'Visible','Off');
                mdb.master.TX2_select = 0;
            end
            
            if (strcmp(value,'Tune'))
                mdb.TX2.stimulus.stimulusSelect.pureTone = 1;
            %     set(handles.TX1_AmpSelect_slider,'Max',150);
            %     set(handles.TX1_AmpSelect_slider,'Min',-75);
            
            elseif(strcmp(value,'Noise'))
                mdb.TX2.stimulus.stimulusSelect.noise = 1;
            %     set(handles.TX1_AmpSelect_slider,'Max',125);
            %     set(handles.TX1_AmpSelect_slider,'Min',0);
            elseif(strcmp(value,'Speech'))
                mdb.TX2.stimulus.stimulusSelect.speech = 1;
            
            end
            save mdb mdb;
        end

        % Button pushed function: mapSpeakers
        function mapSpeakers_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mapSpeakers (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            top = imread('Maps/SpeakersTop.jpg');
            horizontal = imread('Maps/SpeakersLow.jpg');
            figure();
            subplot(1,2,1);
            imshow(top);
            title('Speakers Numbers in top level');
            subplot(1,2,2);
            imshow(horizontal);
            title('Speakers Numbers in horizontal level');
        end

        % Value changed function: startPauseButton
        function startPauseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            load mdb;
            
            value = get(hObject,'Value');
            if(1 == value )
                set(hObject,'String','Pause');
                [TX1_playMode,TX2_playMode,TX3_playMode] = play_signal_multi(mdb.master.TX1_select,mdb.master.TX2_select,mdb.master.TX3_select);
            
            
            %TODO: check if needed
            %     if (TX1_playMode &&  TX2_playMode &&  TX3_playMode)  %single shot
            %         set(hObject,'String','start'); %reset button
            %         set(hObject,'Value',0);
            %     end
            
            else
                set(hObject,'String','Start');
                TXall_stop_signal();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure('Visible', 'off');
            app.mainFigure.Name = 'TDT_GUI_v2';
            app.mainFigure.Tag = 'mainFigure';
            app.Main = uimenu(app.mainFigure);
            app.Main.Text = 'Main';
            app.Main.Tag = 'Untitled_1';
            app.Menu = uimenu(app.mainFigure);
            app.Menu.Text = 'Menu';
            app.Output1Panel = uipanel(app.mainFigure);
            app.Output1Panel.Title = 'Output Ports Selection 1';
            app.Output1Panel.Visible = 'off';
            app.Output1Panel.Tag = 'Output1Panel';
            app.TX1FF1 = uicheckbox(app.Output1Panel);
            app.TX1FF1.ValueChangedFcn = createCallbackFcn(app, @TX1FF1_Callback, true);
            app.TX1FF1.Tag = 'TX1FF1';
            app.TX1FF1.Text = '';
            app.TX1FF2 = uicheckbox(app.Output1Panel);
            app.TX1FF2.ValueChangedFcn = createCallbackFcn(app, @TX1FF2_Callback, true);
            app.TX1FF2.Tag = 'TX1FF2';
            app.TX1FF2.Text = '';
            app.TX1FF3 = uicheckbox(app.Output1Panel);
            app.TX1FF3.ValueChangedFcn = createCallbackFcn(app, @TX1FF3_Callback, true);
            app.TX1FF3.Tag = 'TX1FF3';
            app.TX1FF3.Text = '';
            app.TX1FF4 = uicheckbox(app.Output1Panel);
            app.TX1FF4.ValueChangedFcn = createCallbackFcn(app, @TX1FF4_Callback, true);
            app.TX1FF4.Tag = 'TX1FF4';
            app.TX1FF4.Text = '';
            app.TX1FF5 = uicheckbox(app.Output1Panel);
            app.TX1FF5.ValueChangedFcn = createCallbackFcn(app, @TX1FF5_Callback, true);
            app.TX1FF5.Tag = 'TX1FF5';
            app.TX1FF5.Text = '';
            app.TX1FF6 = uicheckbox(app.Output1Panel);
            app.TX1FF6.ValueChangedFcn = createCallbackFcn(app, @TX1FF6_Callback, true);
            app.TX1FF6.Tag = 'TX1FF6';
            app.TX1FF6.Text = '';
            app.TX1FF7 = uicheckbox(app.Output1Panel);
            app.TX1FF7.ValueChangedFcn = createCallbackFcn(app, @TX1FF7_Callback, true);
            app.TX1FF7.Tag = 'TX1FF7';
            app.TX1FF7.Text = '';
            app.TX1FF8 = uicheckbox(app.Output1Panel);
            app.TX1FF8.ValueChangedFcn = createCallbackFcn(app, @TX1FF8_Callback, true);
            app.TX1FF8.Tag = 'TX1FF8';
            app.TX1FF8.Text = '';
            app.TX1Properties = uilabel(app.Output1Panel);
            app.TX1Properties.Tag = 'TX1Properties';
            app.TX1Properties.Text = 'Properties:';
            app.TX1_title = uipanel(app.mainFigure);
            app.TX1_title.Title = 'Input Signals (TX1)';
            app.TX1_title.Tag = 'TX1_title';
            app.OptionsButton1 = uibutton(app.TX1_title, 'push');
            app.OptionsButton1.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton1_Callback, true);
            app.OptionsButton1.Tag = 'OptionsButton1';
            app.OptionsButton1.Visible = 'off';
            app.OptionsButton1.Text = 'Options';
            app.TX1 = uidropdown(app.TX1_title);
            app.TX1.Items = {'None', 'Tune', 'Noise', 'Speech'};
            app.TX1.ValueChangedFcn = createCallbackFcn(app, @TX1_Callback, true);
            app.TX1.Tag = 'TX1';
            app.TX1.Value = 'None';
            app.startPauseButton = uibutton(app.mainFigure, 'state');
            app.startPauseButton.ValueChangedFcn = createCallbackFcn(app, @startPauseButton_Callback, true);
            app.startPauseButton.Tag = 'startPauseButton';
            app.startPauseButton.Text = {'Start'; 'Pause'};
            app.uiSpeakerPanels = uipanel(app.mainFigure);
            app.uiSpeakerPanels.Title = 'Output Ports (FF)';
            app.uiSpeakerPanels.Tag = 'uiSpeakerPanels';
            app.speakerText1 = uilabel(app.uiSpeakerPanels);
            app.speakerText1.Tag = 'speakerText1';
            app.speakerText1.Text = '1';
            app.speakerText2 = uilabel(app.uiSpeakerPanels);
            app.speakerText2.Tag = 'speakerText2';
            app.speakerText2.Text = '2';
            app.speakerText3 = uilabel(app.uiSpeakerPanels);
            app.speakerText3.Tag = 'speakerText3';
            app.speakerText3.Text = '3';
            app.speakerText4 = uilabel(app.uiSpeakerPanels);
            app.speakerText4.Tag = 'speakerText4';
            app.speakerText4.Text = '4';
            app.speakerText5 = uilabel(app.uiSpeakerPanels);
            app.speakerText5.Tag = 'speakerText5';
            app.speakerText5.Text = '5';
            app.speakerText6 = uilabel(app.uiSpeakerPanels);
            app.speakerText6.Tag = 'speakerText6';
            app.speakerText6.Text = '6';
            app.speakerText7 = uilabel(app.uiSpeakerPanels);
            app.speakerText7.Tag = 'speakerText7';
            app.speakerText7.Text = '7';
            app.speakerText8 = uilabel(app.uiSpeakerPanels);
            app.speakerText8.Tag = 'speakerText8';
            app.speakerText8.Text = '8';
            app.SpeakersMapButton = uibutton(app.uiSpeakerPanels, 'push');
            app.SpeakersMapButton.ButtonPushedFcn = createCallbackFcn(app, @SpeakersMapButton_Callback, true);
            app.SpeakersMapButton.Tag = 'SpeakersMapButton';
            app.SpeakersMapButton.Text = 'Map';
            app.mapSpeakers = uibutton(app.mainFigure, 'push');
            app.mapSpeakers.ButtonPushedFcn = createCallbackFcn(app, @mapSpeakers_Callback, true);
            app.mapSpeakers.Tag = 'mapSpeakers';
            app.mapSpeakers.Text = 'Map Of Speakers Room';
            app.TX2_title = uipanel(app.mainFigure);
            app.TX2_title.Title = 'Input Signals (TX2)';
            app.TX2_title.Tag = 'TX2_title';
            app.OptionsButton2 = uibutton(app.TX2_title, 'push');
            app.OptionsButton2.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton2_Callback, true);
            app.OptionsButton2.Tag = 'OptionsButton2';
            app.OptionsButton2.Visible = 'off';
            app.OptionsButton2.Text = 'Options';
            app.TX2 = uidropdown(app.TX2_title);
            app.TX2.Items = {'None', 'Tune', 'Noise', 'Speech'};
            app.TX2.ValueChangedFcn = createCallbackFcn(app, @TX2_Callback, true);
            app.TX2.Tag = 'TX2';
            app.TX2.Value = 'None';
            app.Output2Panel = uipanel(app.mainFigure);
            app.Output2Panel.Title = 'Output Ports Selection 2';
            app.Output2Panel.Visible = 'off';
            app.Output2Panel.Tag = 'Output2Panel';
            app.TX2FF1 = uicheckbox(app.Output2Panel);
            app.TX2FF1.ValueChangedFcn = createCallbackFcn(app, @TX2FF1_Callback, true);
            app.TX2FF1.Tag = 'TX2FF1';
            app.TX2FF1.Text = '';
            app.TX2FF2 = uicheckbox(app.Output2Panel);
            app.TX2FF2.ValueChangedFcn = createCallbackFcn(app, @TX2FF2_Callback, true);
            app.TX2FF2.Tag = 'TX2FF2';
            app.TX2FF2.Text = '';
            app.TX2FF3 = uicheckbox(app.Output2Panel);
            app.TX2FF3.ValueChangedFcn = createCallbackFcn(app, @TX2FF3_Callback, true);
            app.TX2FF3.Tag = 'TX2FF3';
            app.TX2FF3.Text = '';
            app.TX2FF4 = uicheckbox(app.Output2Panel);
            app.TX2FF4.ValueChangedFcn = createCallbackFcn(app, @TX2FF4_Callback, true);
            app.TX2FF4.Tag = 'TX2FF4';
            app.TX2FF4.Text = '';
            app.TX2FF5 = uicheckbox(app.Output2Panel);
            app.TX2FF5.ValueChangedFcn = createCallbackFcn(app, @TX2FF5_Callback, true);
            app.TX2FF5.Tag = 'TX2FF5';
            app.TX2FF5.Text = '';
            app.TX2FF6 = uicheckbox(app.Output2Panel);
            app.TX2FF6.ValueChangedFcn = createCallbackFcn(app, @TX2FF6_Callback, true);
            app.TX2FF6.Tag = 'TX2FF6';
            app.TX2FF6.Text = '';
            app.TX2FF7 = uicheckbox(app.Output2Panel);
            app.TX2FF7.ValueChangedFcn = createCallbackFcn(app, @TX2FF7_Callback, true);
            app.TX2FF7.Tag = 'TX2FF7';
            app.TX2FF7.Text = '';
            app.TX2FF8 = uicheckbox(app.Output2Panel);
            app.TX2FF8.ValueChangedFcn = createCallbackFcn(app, @TX2FF8_Callback, true);
            app.TX2FF8.Tag = 'TX2FF8';
            app.TX2FF8.Text = '';
            app.TX2Properties = uilabel(app.Output2Panel);
            app.TX2Properties.Tag = 'TX2Properties';
            app.TX2Properties.Text = 'Properties:';
            % Show the figure after all components are created
            app.mainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TDT_GUI_v2_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.mainFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)TDT_GUI_v2_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.mainFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end