classdef TDT_GUI_v3_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MainTab                       matlab.ui.container.Tab
        InputSignal1TX1DropDownLabel  matlab.ui.control.Label
        InputSignal1TX1DropDown       matlab.ui.control.DropDown
        OutputPortsFFPanel            matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        MapPortsButton                matlab.ui.control.Button
        OutputSelection1Panel         matlab.ui.container.Panel
        TX1FF1                        matlab.ui.control.CheckBox
        TX1FF2                        matlab.ui.control.CheckBox
        TX1FF3                        matlab.ui.control.CheckBox
        TX1FF4                        matlab.ui.control.CheckBox
        TX1FF8                        matlab.ui.control.CheckBox
        TX1FF7                        matlab.ui.control.CheckBox
        TX1FF6                        matlab.ui.control.CheckBox
        TX1FF5                        matlab.ui.control.CheckBox
        OptionsTX1Button              matlab.ui.control.Button
        MapOfRoomSpeakersButton       matlab.ui.control.Button
        StartButton                   matlab.ui.control.StateButton
        InputSignal2TX2DropDownLabel  matlab.ui.control.Label
        InputSignal2TX2DropDown       matlab.ui.control.DropDown
        OptionsTX2Button              matlab.ui.control.Button
        OutputSelection2Panel         matlab.ui.container.Panel
        TX2FF1                        matlab.ui.control.CheckBox
        TX2FF2                        matlab.ui.control.CheckBox
        TX2FF3                        matlab.ui.control.CheckBox
        TX2FF4                        matlab.ui.control.CheckBox
        TX2FF8                        matlab.ui.control.CheckBox
        TX2FF7                        matlab.ui.control.CheckBox
        TX2FF6                        matlab.ui.control.CheckBox
        TX2FF5                        matlab.ui.control.CheckBox
        OptionsTX3Button              matlab.ui.control.Button
        InputSignal3TX3DropDownLabel  matlab.ui.control.Label
        InputSignal3TX3DropDown       matlab.ui.control.DropDown
        OutputSelection3Panel         matlab.ui.container.Panel
        TX3FF1                        matlab.ui.control.CheckBox
        TX3FF2                        matlab.ui.control.CheckBox
        TX3FF3                        matlab.ui.control.CheckBox
        TX3FF4                        matlab.ui.control.CheckBox
        TX3FF8                        matlab.ui.control.CheckBox
        TX3FF7                        matlab.ui.control.CheckBox
        TX3FF6                        matlab.ui.control.CheckBox
        TX3FF5                        matlab.ui.control.CheckBox
        ExportButton                  matlab.ui.control.Button
        TextMenu                      matlab.ui.control.Label
        FlowExperimentTab             matlab.ui.container.Tab
        HereyouwillabletorunexpreimentLabel  matlab.ui.control.Label
        ImportExcelButton             matlab.ui.control.StateButton
        PlayPauseButton               matlab.ui.control.StateButton
        SNRFinderTab                  matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        OutputPortsFFPanel_2          matlab.ui.container.Panel
        Label_12                      matlab.ui.control.Label
        Label_13                      matlab.ui.control.Label
        Label_14                      matlab.ui.control.Label
        Label_15                      matlab.ui.control.Label
        Label_16                      matlab.ui.control.Label
        Label_17                      matlab.ui.control.Label
        Label_18                      matlab.ui.control.Label
        Label_19                      matlab.ui.control.Label
        StartButton_2                 matlab.ui.control.Button
        SNRFinderLabel                matlab.ui.control.Label
        Label_11                      matlab.ui.control.Label
        ChooseFolderButton            matlab.ui.control.Button
        SignaldbEditFieldLabel        matlab.ui.control.Label
        SignaldbEditField             matlab.ui.control.NumericEditField
        StartingConditionsLabel       matlab.ui.control.Label
        NoisedbEditFieldLabel         matlab.ui.control.Label
        NoisedbEditField              matlab.ui.control.NumericEditField
        NameoffolderEditFieldLabel    matlab.ui.control.Label
        NameoffolderEditField         matlab.ui.control.EditField
        OutputSettingsLabel           matlab.ui.control.Label
        CUsersLabDocumentsButton      matlab.ui.control.Button
        AdvancedSettingsButton        matlab.ui.control.Button
        OutputdirLabel                matlab.ui.control.Label
        SignalOutputSelectionPanel    matlab.ui.container.Panel
        SNRSignalOutput1              matlab.ui.control.CheckBox
        SNRSignalOutput2              matlab.ui.control.CheckBox
        SNRSignalOutput3              matlab.ui.control.CheckBox
        SNRSignalOutput4              matlab.ui.control.CheckBox
        SNRSignalOutput8              matlab.ui.control.CheckBox
        SNRSignalOutput7              matlab.ui.control.CheckBox
        SNRSignalOutput6              matlab.ui.control.CheckBox
        SNRSignalOutput5              matlab.ui.control.CheckBox
        NoiseOutputSelectionPanel     matlab.ui.container.Panel
        SNRNoiseOutput1               matlab.ui.control.CheckBox
        SNRNoiseOutput2               matlab.ui.control.CheckBox
        SNRNoiseOutput3               matlab.ui.control.CheckBox
        SNRNoiseOutput4               matlab.ui.control.CheckBox
        SNRNoiseOutput8               matlab.ui.control.CheckBox
        SNRNoiseOutput7               matlab.ui.control.CheckBox
        SNRNoiseOutput6               matlab.ui.control.CheckBox
        SNRNoiseOutput5               matlab.ui.control.CheckBox
        CalibrationTab                matlab.ui.container.Tab
        Speaker1SpinnerLabel          matlab.ui.control.Label
        Speaker1Spinner               matlab.ui.control.Spinner
        ImportButton                  matlab.ui.control.Button
        CalibExportButton             matlab.ui.control.Button
        Speaker2SpinnerLabel          matlab.ui.control.Label
        Speaker2Spinner               matlab.ui.control.Spinner
        Speaker3SpinnerLabel          matlab.ui.control.Label
        Speaker3Spinner               matlab.ui.control.Spinner
        Speaker4SpinnerLabel          matlab.ui.control.Label
        Speaker4Spinner               matlab.ui.control.Spinner
        Speaker8SpinnerLabel          matlab.ui.control.Label
        Speaker8Spinner               matlab.ui.control.Spinner
        Speaker7SpinnerLabel          matlab.ui.control.Label
        Speaker7Spinner               matlab.ui.control.Spinner
        Speaker6SpinnerLabel          matlab.ui.control.Label
        Speaker6Spinner               matlab.ui.control.Spinner
        Speaker5SpinnerLabel          matlab.ui.control.Label
        Speaker5Spinner               matlab.ui.control.Spinner
        Speaker9SpinnerLabel          matlab.ui.control.Label
        Speaker9Spinner               matlab.ui.control.Spinner
        Speaker10SpinnerLabel         matlab.ui.control.Label
        Speaker10Spinner              matlab.ui.control.Spinner
        Speaker11SpinnerLabel         matlab.ui.control.Label
        Speaker11Spinner              matlab.ui.control.Spinner
        Speaker12SpinnerLabel         matlab.ui.control.Label
        Speaker12Spinner              matlab.ui.control.Spinner
        Speaker13SpinnerLabel         matlab.ui.control.Label
        Speaker13Spinner              matlab.ui.control.Spinner
        Speaker14SpinnerLabel         matlab.ui.control.Label
        Speaker14Spinner              matlab.ui.control.Spinner
        Speaker15SpinnerLabel         matlab.ui.control.Label
        Speaker15Spinner              matlab.ui.control.Spinner
        Speaker16SpinnerLabel         matlab.ui.control.Label
        Speaker16Spinner              matlab.ui.control.Spinner
        Speaker17SpinnerLabel         matlab.ui.control.Label
        Speaker17Spinner              matlab.ui.control.Spinner
        Speaker18SpinnerLabel         matlab.ui.control.Label
        Speaker18Spinner              matlab.ui.control.Spinner
        FFOutput1EditFieldLabel       matlab.ui.control.Label
        FFOutput1EditField            matlab.ui.control.NumericEditField
        SpeakersLabel                 matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        FFOutput2EditFieldLabel       matlab.ui.control.Label
        FFOutput2EditField            matlab.ui.control.NumericEditField
        FFOutput4EditFieldLabel       matlab.ui.control.Label
        FFOutput4EditField            matlab.ui.control.NumericEditField
        FFOutput3EditFieldLabel       matlab.ui.control.Label
        FFOutput3EditField            matlab.ui.control.NumericEditField
        FFOutput5EditFieldLabel       matlab.ui.control.Label
        FFOutput5EditField            matlab.ui.control.NumericEditField
        FFOutput6EditFieldLabel       matlab.ui.control.Label
        FFOutput6EditField            matlab.ui.control.NumericEditField
        FFOutput8EditFieldLabel       matlab.ui.control.Label
        FFOutput8EditField            matlab.ui.control.NumericEditField
        FFOutput7EditFieldLabel       matlab.ui.control.Label
        FFOutput7EditField            matlab.ui.control.NumericEditField
        CalibExportButton_2           matlab.ui.control.Button
        ImportButton_2                matlab.ui.control.Button
        Label_10                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        expriementPool % For background expreiment 
        fileExperiment % future file expriement for parllell computing
        OutputPath % Description
        online = 0 % will block running without TDT if online=1
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.online = ~isfile('./OFFLINE');
            
            global RP;
            global whiteNoise_vector;
            % global parameters;
               
            % save parameters;


            path = strcat(pwd,'\Copy_of_full_4.rcx');
            [RP,status,message] = Circuit_Loader(path);
            statusNumbers = bitget(status,[1 2 3]);
            if(  any(0 == statusNumbers ) )
               idx = find(0==statusNumbers);
               numOfError = idx(1);
               if(1 == numOfError)
                  message = strcat(message,sprintf('\nPlease restart the DTD system'));
               end
               uialert(app.UIFigure,message,'Error');
               if(app.online)
                 close all force;
               end
            end
            Initialize_Selection("TX1");
            Initialize_Selection("TX2");
            Initialize_Selection("TX3");
            Update_Calibration(RP);
            load mdb;
            %update calibration tab screen
            arrayfun(@(ii) setfield(app,strcat('Speaker',num2str(ii),'Spinner'),'Value',mdb.Calibration.SpeakersGain(ii))  , [1:18]);
            arrayfun(@(ii) setfield(app,strcat('FFOutput',num2str(ii),'EditField'),'Value',mdb.Calibration.FF2SpeakerMap(ii))  , [1:8]);

            whiteNoise_vector = randn(1,3000000);
            
        end

        % Button pushed function: MapPortsButton
        function MapPortsButtonPushed(app, event)
            I = imread('Maps/PP16Map.jpg');
            figure();
            imshow(I);
        end

        % Value changed function: InputSignal1TX1DropDown
        function InputSignal1TX1DropDownValueChanged(app, event)
            value = app.InputSignal1TX1DropDown.Value;
            DropValueChangedHelper(app,"TX1",value);
        end

        % Value changed function: TX1FF1
        function TX1FF1ValueChanged(app, event)
            value = app.TX1FF1.Value;
            DacVectorTransducerHelper(app,"TX1",1,value);
        end

        % Value changed function: TX1FF2
        function TX1FF2ValueChanged(app, event)
            value = app.TX1FF2.Value;
             DacVectorTransducerHelper(app,"TX1",2,value);
        end

        % Value changed function: TX1FF3
        function TX1FF3ValueChanged(app, event)
            value = app.TX1FF3.Value;
             DacVectorTransducerHelper(app,"TX1",3,value);
        end

        % Value changed function: TX1FF4
        function TX1FF4ValueChanged(app, event)
            value = app.TX1FF4.Value;
             DacVectorTransducerHelper(app,"TX1",4,value);
        end

        % Value changed function: TX1FF5
        function TX1FF5ValueChanged(app, event)
            value = app.TX1FF5.Value;
             DacVectorTransducerHelper(app,"TX1",5,value);
        end

        % Value changed function: TX1FF6
        function TX1FF6ValueChanged(app, event)
            value = app.TX1FF6.Value;
             DacVectorTransducerHelper(app,"TX1",6,value);
        end

        % Value changed function: TX1FF7
        function TX1FF7ValueChanged(app, event)
            value = app.TX1FF7.Value;
             DacVectorTransducerHelper(app,"TX1",7,value);
        end

        % Value changed function: TX1FF8
        function TX1FF8ValueChanged(app, event)
            value = app.TX1FF8.Value;
            DacVectorTransducerHelper(app,"TX1",8,value);
        end

        % Button pushed function: OptionsTX1Button
        function OptionsTX1ButtonPushed(app, event)
            OptionsMenuOpenerHelper(app,1);
        end

        % Button pushed function: MapOfRoomSpeakersButton
        function MapOfRoomSpeakersButtonPushed(app, event)
            top = imread('Maps/SpeakersTop.jpg');
            horizontal = imread('Maps/SpeakersLow.jpg');
            figure();
            subplot(1,2,1);
            imshow(top);
            title('Speakers Numbers in top level');
            subplot(1,2,2);
            imshow(horizontal);
            title('Speakers Numbers in horizontal level');
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            load mdb;
            value = app.StartButton.Value;
            if(1 == value )
                app.StartButton.Text = "Pause";
                [TX1_playMode,TX2_playMode,TX3_playMode] = play_signal_multi(mdb.master.TX1_select,mdb.master.TX2_select,mdb.master.TX3_select); 
            
                
            %TODO: check if needed    
            %     if (TX1_playMode &&  TX2_playMode &&  TX3_playMode)  %single shot
            %         set(hObject,'String','start'); %reset button
            %         set(hObject,'Value',0);
            %     end
            
            else
                app.StartButton.Text = "Start";
                TXall_stop_signal();
            end
            
        end

        % Value changed function: InputSignal2TX2DropDown
        function InputSignal2TX2DropDownValueChanged(app, event)
            value = app.InputSignal2TX2DropDown.Value;
            DropValueChangedHelper(app,"TX2",value);
        end

        % Button pushed function: OptionsTX2Button
        function OptionsTX2ButtonPushed(app, event)
              OptionsMenuOpenerHelper(app,2);
        end

        % Value changed function: TX2FF1
        function TX2FF1ValueChanged(app, event)
            value = app.TX2FF1.Value;
            DacVectorTransducerHelper(app,"TX2",1,value);
        end

        % Value changed function: TX2FF2
        function TX2FF2ValueChanged(app, event)
            value = app.TX2FF2.Value;
            DacVectorTransducerHelper(app,"TX2",2,value);
        end

        % Value changed function: TX2FF3
        function TX2FF3ValueChanged(app, event)
            value = app.TX2FF3.Value;
            DacVectorTransducerHelper(app,"TX2",3,value);
        end

        % Value changed function: TX2FF4
        function TX2FF4ValueChanged(app, event)
            value = app.TX2FF4.Value;
            DacVectorTransducerHelper(app,"TX2",4,value);
        end

        % Value changed function: TX2FF5
        function TX2FF5ValueChanged(app, event)
            value = app.TX2FF5.Value;
            DacVectorTransducerHelper(app,"TX2",5,value);
        end

        % Value changed function: TX2FF6
        function TX2FF6ValueChanged(app, event)
            value = app.TX2FF6.Value;
            DacVectorTransducerHelper(app,"TX2",6,value);
        end

        % Value changed function: TX2FF7
        function TX2FF7ValueChanged(app, event)
            value = app.TX2FF7.Value;
            DacVectorTransducerHelper(app,"TX2",7,value);
        end

        % Value changed function: TX2FF8
        function TX2FF8ValueChanged(app, event)
            value = app.TX2FF8.Value;
            DacVectorTransducerHelper(app,"TX2",8,value);
        end

        % Value changed function: TX3FF1
        function TX3FF1ValueChanged(app, event)
            value = app.TX3FF1.Value;
             DacVectorTransducerHelper(app,"TX3",1,value);
        end

        % Value changed function: TX3FF2
        function TX3FF2ValueChanged(app, event)
            value = app.TX3FF2.Value;
            DacVectorTransducerHelper(app,"TX3",2,value);
        end

        % Value changed function: TX3FF3
        function TX3FF3ValueChanged(app, event)
            value = app.TX3FF3.Value;
            DacVectorTransducerHelper(app,"TX3",3,value);
        end

        % Value changed function: TX3FF4
        function TX3FF4ValueChanged(app, event)
            value = app.TX3FF4.Value;
            DacVectorTransducerHelper(app,"TX3",4,value);
        end

        % Value changed function: TX3FF5
        function TX3FF5ValueChanged(app, event)
            value = app.TX3FF5.Value;
            DacVectorTransducerHelper(app,"TX3",5,value);
        end

        % Value changed function: TX3FF6
        function TX3FF6ValueChanged(app, event)
            value = app.TX3FF6.Value;
            DacVectorTransducerHelper(app,"TX3",6,value);
            
        end

        % Value changed function: TX3FF7
        function TX3FF7ValueChanged(app, event)
            value = app.TX3FF7.Value;
            DacVectorTransducerHelper(app,"TX3",7,value);
        end

        % Value changed function: TX3FF8
        function TX3FF8ValueChanged(app, event)
            value = app.TX3FF8.Value;
            DacVectorTransducerHelper(app,"TX3",8,value);
        end

        % Value changed function: InputSignal3TX3DropDown
        function InputSignal3TX3DropDownValueChanged(app, event)
            value = app.InputSignal3TX3DropDown.Value;
            DropValueChangedHelper(app,"TX3",value);

        end

        % Button pushed function: OptionsTX3Button
        function OptionsTX3ButtonPushed(app, event)
             OptionsMenuOpenerHelper(app,3);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
%             TODO Add Logic for export button
        end

        % Value changed function: ImportExcelButton
        function ImportExcelButtonValueChanged(app, event)
            value = app.ImportExcelButton.Value;
           [FileName,PathName,FilterIndex] = uigetfile('Documents\*.xlsx');
                   if(FileName == 0)
                       return;
                   end
           
           app.fileExperiment  = fullfile(PathName,FileName);
%            app.scriptExperiment = readcell(fullfile(PathName,FileName));
           app.ImportExcelButton.Text = FileName;
           app.PlayPauseButton.Enable = 1;
           
        end

        % Value changed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)
            value = app.PlayPauseButton.Value;
            if(0 == value)

                %TODO make resart and resume buttons
                %TODO make it non-blocking function https://www.mathworks.com/help/parallel-computing/parallel.pool.parfeval.html#mw_130ecf71-d0dc-485c-b230-99fc0e9107e7
                app.PlayPauseButton.Text = 'Pause';
                HandleExpriement(readcell(app.fileExperiment),app.PlayPauseButton);
%                 app.expriementPool = parfeval(@HandleExpriement,0, app.scriptExperiment,app.PlayPauseButton);
            elseif(1 == value)
                app.PlayPauseButton.Text = 'Start';
%                 cancel(app.expriementPool);
            end
                
        end

        % Button pushed function: CalibExportButton
        function CalibExportButtonPushed(app, event)
            gains = arrayfun(@(ii) getfield(app,strcat('Speaker',num2str(ii),'Spinner'),'Value')  , [1:18]);
            [file, path] = uiputfile(strcat('C:\Users\Lab\Documents\Gain of Speakers - ',date,'.xlsx'));
            T = table([1:18]',gains');
            T.Properties.VariableNames = ["Speaker Number","Gain (db)"];
            writetable(T,fullfile(path,file) ,'Sheet',1,'Range','A1');
        end

        % Value changed function: Speaker1Spinner
        function Speaker1SpinnerValueChanged(app, event)
            value = app.Speaker1Spinner.Value;
            UpdateGain(1,value);
        end

        % Value changed function: Speaker2Spinner
        function Speaker2SpinnerValueChanged(app, event)
            value = app.Speaker2Spinner.Value;
            UpdateGain(2,value);
        end

        % Value changed function: Speaker3Spinner
        function Speaker3SpinnerValueChanged(app, event)
            value = app.Speaker3Spinner.Value;
            UpdateGain(3,value);
        end

        % Value changed function: Speaker4Spinner
        function Speaker4SpinnerValueChanged(app, event)
            value = app.Speaker4Spinner.Value;
            UpdateGain(4,value);
        end

        % Value changed function: Speaker5Spinner
        function Speaker5SpinnerValueChanged(app, event)
            value = app.Speaker5Spinner.Value;
            UpdateGain(5,value);
        end

        % Value changed function: Speaker6Spinner
        function Speaker6SpinnerValueChanged(app, event)
            value = app.Speaker6Spinner.Value;
            UpdateGain(6,value);
        end

        % Value changed function: Speaker7Spinner
        function Speaker7SpinnerValueChanged(app, event)
            value = app.Speaker7Spinner.Value;
            UpdateGain(7,value);
        end

        % Value changed function: Speaker8Spinner
        function Speaker8SpinnerValueChanged(app, event)
            value = app.Speaker8Spinner.Value;
            UpdateGain(8,value);
        end

        % Value changed function: Speaker9Spinner
        function Speaker9SpinnerValueChanged(app, event)
            value = app.Speaker9Spinner.Value;
            UpdateGain(9,value);
        end

        % Value changed function: Speaker10Spinner
        function Speaker10SpinnerValueChanged(app, event)
            value = app.Speaker10Spinner.Value;
            UpdateGain(10,value);
        end

        % Value changed function: Speaker11Spinner
        function Speaker11SpinnerValueChanged(app, event)
            value = app.Speaker11Spinner.Value;
            UpdateGain(11,value);
        end

        % Value changed function: Speaker12Spinner
        function Speaker12SpinnerValueChanged(app, event)
            value = app.Speaker12Spinner.Value;
            UpdateGain(12,value);
        end

        % Value changed function: Speaker13Spinner
        function Speaker13SpinnerValueChanged(app, event)
            value = app.Speaker13Spinner.Value;
            UpdateGain(13,value);
        end

        % Value changed function: Speaker14Spinner
        function Speaker14SpinnerValueChanged(app, event)
            value = app.Speaker14Spinner.Value;
            UpdateGain(14,value);
        end

        % Value changed function: Speaker15Spinner
        function Speaker15SpinnerValueChanged(app, event)
            value = app.Speaker15Spinner.Value;
            UpdateGain(15,value);
        end

        % Value changed function: Speaker16Spinner
        function Speaker16SpinnerValueChanged(app, event)
            value = app.Speaker16Spinner.Value;
            UpdateGain(16,value);
        end

        % Value changed function: Speaker17Spinner
        function Speaker17SpinnerValueChanged(app, event)
            value = app.Speaker17Spinner.Value;
            UpdateGain(17,value);
        end

        % Value changed function: Speaker18Spinner
        function Speaker18SpinnerValueChanged(app, event)
            value = app.Speaker18Spinner.Value;
            UpdateGain(18,value);
        end

        % Value changed function: FFOutput1EditField
        function FFOutput1EditFieldValueChanged(app, event)
            value = app.FFOutput1EditField.Value;
            UpdateFF2SpeakerMap(1,value);
        end

        % Value changed function: FFOutput2EditField
        function FFOutput2EditFieldValueChanged(app, event)
            value = app.FFOutput2EditField.Value;
            UpdateFF2SpeakerMap(2,value);
        end

        % Value changed function: FFOutput3EditField
        function FFOutput3EditFieldValueChanged(app, event)
            value = app.FFOutput3EditField.Value;
            UpdateFF2SpeakerMap(3,value);
        end

        % Value changed function: FFOutput4EditField
        function FFOutput4EditFieldValueChanged(app, event)
            value = app.FFOutput4EditField.Value;
            UpdateFF2SpeakerMap(4,value);
        end

        % Value changed function: FFOutput5EditField
        function FFOutput5EditFieldValueChanged(app, event)
            value = app.FFOutput5EditField.Value;
            UpdateFF2SpeakerMap(5,value);
        end

        % Value changed function: FFOutput6EditField
        function FFOutput6EditFieldValueChanged(app, event)
            value = app.FFOutput6EditField.Value;
            UpdateFF2SpeakerMap(6,value);
        end

        % Value changed function: FFOutput7EditField
        function FFOutput7EditFieldValueChanged(app, event)
            value = app.FFOutput7EditField.Value;
            UpdateFF2SpeakerMap(7,value);
        end

        % Value changed function: FFOutput8EditField
        function FFOutput8EditFieldValueChanged(app, event)
            value = app.FFOutput8EditField.Value;
            UpdateFF2SpeakerMap(8,value);
        end

        % Button pushed function: CalibExportButton_2
        function CalibExportButton_2Pushed(app, event)
            map = arrayfun(@(ii) getfield(app,strcat('FFOutput',num2str(ii),'EditField'),'Value')  , [1:8]);
            [file, path] = uiputfile(strcat('C:\Users\Lab\Documents\Map of FF2Speakers - ',date,'.xlsx'));
            T = table([1:8]',map');
            T.Properties.VariableNames = ["FF Channel Number","Speaker Number Mapping"];
            writetable(T,fullfile(path,file) ,'Sheet',1,'Range','A1');
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
             [FileName,PathName] = uigetfile('Documents\*.xlsx');
             T = readtable(fullfile(PathName,FileName));
             if(length(T.Gain_db_) ~= 18 )
               errordlg('Not a speakers gain file format.','Invalid File format');
               return;
             end
             load mdb
             mdb.Calibration.SpeakersGain = T.Gain_db_';
             arrayfun(@(ii) setfield(app,strcat('Speaker',num2str(ii),'Spinner'),'Value',mdb.Calibration.SpeakersGain(ii))  , [1:18]); 
             save mdb;
             global RP;
             Update_Calibration(RP);
             
        end

        % Button pushed function: ImportButton_2
        function ImportButton_2Pushed(app, event)
             [FileName,PathName] = uigetfile('Documents\*.xlsx');
             T = readtable(fullfile(PathName,FileName));
             if(length(T.SpeakerNumberMapping) ~= 8 )
               errordlg('Not a FF to speaker map file format.','Invalid File format');
               return;
             end
             load mdb;
             mdb.Calibration.FF2SpeakerMap= T.SpeakerNumberMapping';
             arrayfun(@(ii) setfield(app,strcat('FFOutput',num2str(ii),'EditField'),'Value',mdb.Calibration.FF2SpeakerMap(ii))  , [1:8]);
             save mdb mdb;
             global RP;
             Update_Calibration(RP);
             
        end

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
              app.ChooseFolderButton.Text =  uigetdir('C:\Users\Lab\Documents\Words Files\');
              app.Panel.Enable = 'on';
        end

        % Button pushed function: CUsersLabDocumentsButton
        function CUsersLabDocumentsButtonPushed(app, event)
            app.OutputPath = uigetdir('C:\Users\Lab\Documents');
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            SNRFinderHelper(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if(strcmp(selectedTab.Title,'SNR Finder'))
                app.NameoffolderEditField.Value = replace(char(datetime) ,':','-');
            end
            Initialize_Selection("TX1");
            Initialize_Selection("TX2");
            Initialize_Selection("TX3");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.InputSignal1TX1DropDownLabel = uilabel(app.MainTab);
            app.InputSignal1TX1DropDownLabel.Text = 'Input Signal 1 (TX1)';
            app.InputSignal1TX1DropDown = uidropdown(app.MainTab);
            app.InputSignal1TX1DropDown.Items = {'None', 'Pure Tune', 'Noise', 'From File'};
            app.InputSignal1TX1DropDown.ValueChangedFcn = createCallbackFcn(app, @InputSignal1TX1DropDownValueChanged, true);
            app.InputSignal1TX1DropDown.Value = 'None';
            app.OutputPortsFFPanel = uipanel(app.MainTab);
            app.OutputPortsFFPanel.Title = 'Output Ports (FF)';
            app.Label = uilabel(app.OutputPortsFFPanel);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.OutputPortsFFPanel);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.OutputPortsFFPanel);
            app.Label_3.Text = '4';
            app.Label_4 = uilabel(app.OutputPortsFFPanel);
            app.Label_4.Text = '3';
            app.Label_5 = uilabel(app.OutputPortsFFPanel);
            app.Label_5.Text = '8';
            app.Label_6 = uilabel(app.OutputPortsFFPanel);
            app.Label_6.Text = '7';
            app.Label_7 = uilabel(app.OutputPortsFFPanel);
            app.Label_7.Text = '6';
            app.Label_8 = uilabel(app.OutputPortsFFPanel);
            app.Label_8.Text = '5';
            app.MapPortsButton = uibutton(app.OutputPortsFFPanel, 'push');
            app.MapPortsButton.ButtonPushedFcn = createCallbackFcn(app, @MapPortsButtonPushed, true);
            app.MapPortsButton.Text = 'Map';
            app.OutputSelection1Panel = uipanel(app.MainTab);
            app.OutputSelection1Panel.Title = 'Output Selection 1';
            app.TX1FF1 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF1.ValueChangedFcn = createCallbackFcn(app, @TX1FF1ValueChanged, true);
            app.TX1FF1.Text = '';
            app.TX1FF2 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF2.ValueChangedFcn = createCallbackFcn(app, @TX1FF2ValueChanged, true);
            app.TX1FF2.Text = '';
            app.TX1FF3 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF3.ValueChangedFcn = createCallbackFcn(app, @TX1FF3ValueChanged, true);
            app.TX1FF3.Text = '';
            app.TX1FF4 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF4.ValueChangedFcn = createCallbackFcn(app, @TX1FF4ValueChanged, true);
            app.TX1FF4.Text = '';
            app.TX1FF8 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF8.ValueChangedFcn = createCallbackFcn(app, @TX1FF8ValueChanged, true);
            app.TX1FF8.Text = '';
            app.TX1FF7 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF7.ValueChangedFcn = createCallbackFcn(app, @TX1FF7ValueChanged, true);
            app.TX1FF7.Text = '';
            app.TX1FF6 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF6.ValueChangedFcn = createCallbackFcn(app, @TX1FF6ValueChanged, true);
            app.TX1FF6.Text = '';
            app.TX1FF5 = uicheckbox(app.OutputSelection1Panel);
            app.TX1FF5.ValueChangedFcn = createCallbackFcn(app, @TX1FF5ValueChanged, true);
            app.TX1FF5.Text = '';
            app.OptionsTX1Button = uibutton(app.MainTab, 'push');
            app.OptionsTX1Button.ButtonPushedFcn = createCallbackFcn(app, @OptionsTX1ButtonPushed, true);
            app.OptionsTX1Button.Enable = 'off';
            app.OptionsTX1Button.Text = 'Options';
            app.MapOfRoomSpeakersButton = uibutton(app.MainTab, 'push');
            app.MapOfRoomSpeakersButton.ButtonPushedFcn = createCallbackFcn(app, @MapOfRoomSpeakersButtonPushed, true);
            app.MapOfRoomSpeakersButton.Text = 'Map Of Room Speakers';
            app.StartButton = uibutton(app.MainTab, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.InputSignal2TX2DropDownLabel = uilabel(app.MainTab);
            app.InputSignal2TX2DropDownLabel.Text = 'Input Signal 2 (TX2)';
            app.InputSignal2TX2DropDown = uidropdown(app.MainTab);
            app.InputSignal2TX2DropDown.Items = {'None', 'Pure Tune', 'Noise', 'From File'};
            app.InputSignal2TX2DropDown.ValueChangedFcn = createCallbackFcn(app, @InputSignal2TX2DropDownValueChanged, true);
            app.InputSignal2TX2DropDown.Value = 'None';
            app.OptionsTX2Button = uibutton(app.MainTab, 'push');
            app.OptionsTX2Button.ButtonPushedFcn = createCallbackFcn(app, @OptionsTX2ButtonPushed, true);
            app.OptionsTX2Button.Enable = 'off';
            app.OptionsTX2Button.Text = 'Options';
            app.OutputSelection2Panel = uipanel(app.MainTab);
            app.OutputSelection2Panel.Title = 'Output Selection 2';
            app.TX2FF1 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF1.ValueChangedFcn = createCallbackFcn(app, @TX2FF1ValueChanged, true);
            app.TX2FF1.Text = '';
            app.TX2FF2 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF2.ValueChangedFcn = createCallbackFcn(app, @TX2FF2ValueChanged, true);
            app.TX2FF2.Text = '';
            app.TX2FF3 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF3.ValueChangedFcn = createCallbackFcn(app, @TX2FF3ValueChanged, true);
            app.TX2FF3.Text = '';
            app.TX2FF4 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF4.ValueChangedFcn = createCallbackFcn(app, @TX2FF4ValueChanged, true);
            app.TX2FF4.Text = '';
            app.TX2FF8 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF8.ValueChangedFcn = createCallbackFcn(app, @TX2FF8ValueChanged, true);
            app.TX2FF8.Text = '';
            app.TX2FF7 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF7.ValueChangedFcn = createCallbackFcn(app, @TX2FF7ValueChanged, true);
            app.TX2FF7.Text = '';
            app.TX2FF6 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF6.ValueChangedFcn = createCallbackFcn(app, @TX2FF6ValueChanged, true);
            app.TX2FF6.Text = '';
            app.TX2FF5 = uicheckbox(app.OutputSelection2Panel);
            app.TX2FF5.ValueChangedFcn = createCallbackFcn(app, @TX2FF5ValueChanged, true);
            app.TX2FF5.Text = '';
            app.OptionsTX3Button = uibutton(app.MainTab, 'push');
            app.OptionsTX3Button.ButtonPushedFcn = createCallbackFcn(app, @OptionsTX3ButtonPushed, true);
            app.OptionsTX3Button.Enable = 'off';
            app.OptionsTX3Button.Text = 'Options';
            app.InputSignal3TX3DropDownLabel = uilabel(app.MainTab);
            app.InputSignal3TX3DropDownLabel.Text = 'Input Signal 3 (TX3)';
            app.InputSignal3TX3DropDown = uidropdown(app.MainTab);
            app.InputSignal3TX3DropDown.Items = {'None', 'Pure Tune', 'Noise', 'From File'};
            app.InputSignal3TX3DropDown.ValueChangedFcn = createCallbackFcn(app, @InputSignal3TX3DropDownValueChanged, true);
            app.InputSignal3TX3DropDown.Value = 'None';
            app.OutputSelection3Panel = uipanel(app.MainTab);
            app.OutputSelection3Panel.Title = 'Output Selection 3';
            app.TX3FF1 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF1.ValueChangedFcn = createCallbackFcn(app, @TX3FF1ValueChanged, true);
            app.TX3FF1.Text = '';
            app.TX3FF2 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF2.ValueChangedFcn = createCallbackFcn(app, @TX3FF2ValueChanged, true);
            app.TX3FF2.Text = '';
            app.TX3FF3 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF3.ValueChangedFcn = createCallbackFcn(app, @TX3FF3ValueChanged, true);
            app.TX3FF3.Text = '';
            app.TX3FF4 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF4.ValueChangedFcn = createCallbackFcn(app, @TX3FF4ValueChanged, true);
            app.TX3FF4.Text = '';
            app.TX3FF8 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF8.ValueChangedFcn = createCallbackFcn(app, @TX3FF8ValueChanged, true);
            app.TX3FF8.Text = '';
            app.TX3FF7 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF7.ValueChangedFcn = createCallbackFcn(app, @TX3FF7ValueChanged, true);
            app.TX3FF7.Text = '';
            app.TX3FF6 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF6.ValueChangedFcn = createCallbackFcn(app, @TX3FF6ValueChanged, true);
            app.TX3FF6.Text = '';
            app.TX3FF5 = uicheckbox(app.OutputSelection3Panel);
            app.TX3FF5.ValueChangedFcn = createCallbackFcn(app, @TX3FF5ValueChanged, true);
            app.TX3FF5.Text = '';
            app.ExportButton = uibutton(app.MainTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.TextMenu = uilabel(app.MainTab);
            app.TextMenu.Text = '';
            app.FlowExperimentTab = uitab(app.TabGroup);
            app.FlowExperimentTab.Title = 'Flow Experiment';
            app.HereyouwillabletorunexpreimentLabel = uilabel(app.FlowExperimentTab);
            app.HereyouwillabletorunexpreimentLabel.Text = 'Here you will able to run automatic experiment that composed from severel setups/configurations of different inputs and speakers';
            app.ImportExcelButton = uibutton(app.FlowExperimentTab, 'state');
            app.ImportExcelButton.ValueChangedFcn = createCallbackFcn(app, @ImportExcelButtonValueChanged, true);
            app.ImportExcelButton.Text = 'Import Excel';
            app.PlayPauseButton = uibutton(app.FlowExperimentTab, 'state');
            app.PlayPauseButton.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Enable = 'off';
            app.PlayPauseButton.Text = 'Start';
            app.SNRFinderTab = uitab(app.TabGroup);
            app.SNRFinderTab.Title = 'SNR Finder';
            app.Panel = uipanel(app.SNRFinderTab);
            app.Panel.Enable = 'off';
            app.Panel.Title = 'Panel';
            app.OutputPortsFFPanel_2 = uipanel(app.Panel);
            app.OutputPortsFFPanel_2.Title = 'Output Ports (FF)';
            app.Label_12 = uilabel(app.OutputPortsFFPanel_2);
            app.Label_12.Text = '1';
            app.Label_13 = uilabel(app.OutputPortsFFPanel_2);
            app.Label_13.Text = '2';
            app.Label_14 = uilabel(app.OutputPortsFFPanel_2);
            app.Label_14.Text = '4';
            app.Label_15 = uilabel(app.OutputPortsFFPanel_2);
            app.Label_15.Text = '3';
            app.Label_16 = uilabel(app.OutputPortsFFPanel_2);
            app.Label_16.Text = '8';
            app.Label_17 = uilabel(app.OutputPortsFFPanel_2);
            app.Label_17.Text = '7';
            app.Label_18 = uilabel(app.OutputPortsFFPanel_2);
            app.Label_18.Text = '6';
            app.Label_19 = uilabel(app.OutputPortsFFPanel_2);
            app.Label_19.Text = '5';
            app.StartButton_2 = uibutton(app.Panel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.SNRFinderLabel = uilabel(app.SNRFinderTab);
            app.SNRFinderLabel.Text = 'SNR Finder';
            app.Label_11 = uilabel(app.SNRFinderTab);
            app.Label_11.Text = 'Please choose folder directory which include at least 25 .wav audio files that contains word';
            app.ChooseFolderButton = uibutton(app.SNRFinderTab, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.SignaldbEditFieldLabel = uilabel(app.SNRFinderTab);
            app.SignaldbEditFieldLabel.Text = 'Signal (db)';
            app.SignaldbEditField = uieditfield(app.SNRFinderTab, 'numeric');
            app.SignaldbEditField.Value = 60;
            app.StartingConditionsLabel = uilabel(app.SNRFinderTab);
            app.StartingConditionsLabel.Text = 'Starting Conditions';
            app.NoisedbEditFieldLabel = uilabel(app.SNRFinderTab);
            app.NoisedbEditFieldLabel.Text = 'Noise (db)';
            app.NoisedbEditField = uieditfield(app.SNRFinderTab, 'numeric');
            app.NoisedbEditField.Value = 50;
            app.NameoffolderEditFieldLabel = uilabel(app.SNRFinderTab);
            app.NameoffolderEditFieldLabel.Text = 'Name of folder';
            app.NameoffolderEditField = uieditfield(app.SNRFinderTab, 'text');
            app.OutputSettingsLabel = uilabel(app.SNRFinderTab);
            app.OutputSettingsLabel.Text = 'Output Settings';
            app.CUsersLabDocumentsButton = uibutton(app.SNRFinderTab, 'push');
            app.CUsersLabDocumentsButton.ButtonPushedFcn = createCallbackFcn(app, @CUsersLabDocumentsButtonPushed, true);
            app.CUsersLabDocumentsButton.Text = 'C:\Users\Lab\Documents';
            app.AdvancedSettingsButton = uibutton(app.SNRFinderTab, 'push');
            app.AdvancedSettingsButton.Text = 'Advanced Settings';
            app.OutputdirLabel = uilabel(app.SNRFinderTab);
            app.OutputdirLabel.Text = 'Output dir';
            app.SignalOutputSelectionPanel = uipanel(app.SNRFinderTab);
            app.SignalOutputSelectionPanel.Title = 'Signal Output Selection';
            app.SNRSignalOutput1 = uicheckbox(app.SignalOutputSelectionPanel);
            app.SNRSignalOutput1.Text = '';
            app.SNRSignalOutput2 = uicheckbox(app.SignalOutputSelectionPanel);
            app.SNRSignalOutput2.Text = '';
            app.SNRSignalOutput3 = uicheckbox(app.SignalOutputSelectionPanel);
            app.SNRSignalOutput3.Text = '';
            app.SNRSignalOutput4 = uicheckbox(app.SignalOutputSelectionPanel);
            app.SNRSignalOutput4.Text = '';
            app.SNRSignalOutput8 = uicheckbox(app.SignalOutputSelectionPanel);
            app.SNRSignalOutput8.Text = '';
            app.SNRSignalOutput7 = uicheckbox(app.SignalOutputSelectionPanel);
            app.SNRSignalOutput7.Text = '';
            app.SNRSignalOutput6 = uicheckbox(app.SignalOutputSelectionPanel);
            app.SNRSignalOutput6.Text = '';
            app.SNRSignalOutput5 = uicheckbox(app.SignalOutputSelectionPanel);
            app.SNRSignalOutput5.Text = '';
            app.NoiseOutputSelectionPanel = uipanel(app.SNRFinderTab);
            app.NoiseOutputSelectionPanel.Title = 'Noise Output Selection';
            app.SNRNoiseOutput1 = uicheckbox(app.NoiseOutputSelectionPanel);
            app.SNRNoiseOutput1.Text = '';
            app.SNRNoiseOutput2 = uicheckbox(app.NoiseOutputSelectionPanel);
            app.SNRNoiseOutput2.Text = '';
            app.SNRNoiseOutput3 = uicheckbox(app.NoiseOutputSelectionPanel);
            app.SNRNoiseOutput3.Text = '';
            app.SNRNoiseOutput4 = uicheckbox(app.NoiseOutputSelectionPanel);
            app.SNRNoiseOutput4.Text = '';
            app.SNRNoiseOutput8 = uicheckbox(app.NoiseOutputSelectionPanel);
            app.SNRNoiseOutput8.Text = '';
            app.SNRNoiseOutput7 = uicheckbox(app.NoiseOutputSelectionPanel);
            app.SNRNoiseOutput7.Text = '';
            app.SNRNoiseOutput6 = uicheckbox(app.NoiseOutputSelectionPanel);
            app.SNRNoiseOutput6.Text = '';
            app.SNRNoiseOutput5 = uicheckbox(app.NoiseOutputSelectionPanel);
            app.SNRNoiseOutput5.Text = '';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.Speaker1SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker1SpinnerLabel.Text = 'Speaker 1';
            app.Speaker1Spinner = uispinner(app.CalibrationTab);
            app.Speaker1Spinner.Step = 0.1;
            app.Speaker1Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker1SpinnerValueChanged, true);
            app.Speaker1Spinner.Value = 1;
            app.ImportButton = uibutton(app.CalibrationTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.CalibExportButton = uibutton(app.CalibrationTab, 'push');
            app.CalibExportButton.ButtonPushedFcn = createCallbackFcn(app, @CalibExportButtonPushed, true);
            app.CalibExportButton.Text = 'Export';
            app.Speaker2SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker2SpinnerLabel.Text = 'Speaker 2';
            app.Speaker2Spinner = uispinner(app.CalibrationTab);
            app.Speaker2Spinner.Step = 0.1;
            app.Speaker2Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker2SpinnerValueChanged, true);
            app.Speaker2Spinner.Value = 1;
            app.Speaker3SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker3SpinnerLabel.Text = 'Speaker 3';
            app.Speaker3Spinner = uispinner(app.CalibrationTab);
            app.Speaker3Spinner.Step = 0.1;
            app.Speaker3Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker3SpinnerValueChanged, true);
            app.Speaker3Spinner.Value = 1;
            app.Speaker4SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker4SpinnerLabel.Text = 'Speaker 4';
            app.Speaker4Spinner = uispinner(app.CalibrationTab);
            app.Speaker4Spinner.Step = 0.1;
            app.Speaker4Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker4SpinnerValueChanged, true);
            app.Speaker4Spinner.Value = 1;
            app.Speaker8SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker8SpinnerLabel.Text = 'Speaker 8';
            app.Speaker8Spinner = uispinner(app.CalibrationTab);
            app.Speaker8Spinner.Step = 0.1;
            app.Speaker8Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker8SpinnerValueChanged, true);
            app.Speaker8Spinner.Value = 1;
            app.Speaker7SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker7SpinnerLabel.Text = 'Speaker 7';
            app.Speaker7Spinner = uispinner(app.CalibrationTab);
            app.Speaker7Spinner.Step = 0.1;
            app.Speaker7Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker7SpinnerValueChanged, true);
            app.Speaker7Spinner.Value = 1;
            app.Speaker6SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker6SpinnerLabel.Text = 'Speaker 6';
            app.Speaker6Spinner = uispinner(app.CalibrationTab);
            app.Speaker6Spinner.Step = 0.1;
            app.Speaker6Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker6SpinnerValueChanged, true);
            app.Speaker6Spinner.Value = 1;
            app.Speaker5SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker5SpinnerLabel.Text = 'Speaker 5';
            app.Speaker5Spinner = uispinner(app.CalibrationTab);
            app.Speaker5Spinner.Step = 0.1;
            app.Speaker5Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker5SpinnerValueChanged, true);
            app.Speaker5Spinner.Value = 1;
            app.Speaker9SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker9SpinnerLabel.Text = 'Speaker 9';
            app.Speaker9Spinner = uispinner(app.CalibrationTab);
            app.Speaker9Spinner.Step = 0.1;
            app.Speaker9Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker9SpinnerValueChanged, true);
            app.Speaker9Spinner.Value = 1;
            app.Speaker10SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker10SpinnerLabel.Text = 'Speaker 10';
            app.Speaker10Spinner = uispinner(app.CalibrationTab);
            app.Speaker10Spinner.Step = 0.1;
            app.Speaker10Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker10SpinnerValueChanged, true);
            app.Speaker10Spinner.Value = 1;
            app.Speaker11SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker11SpinnerLabel.Text = 'Speaker 11';
            app.Speaker11Spinner = uispinner(app.CalibrationTab);
            app.Speaker11Spinner.Step = 0.1;
            app.Speaker11Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker11SpinnerValueChanged, true);
            app.Speaker11Spinner.Value = 1;
            app.Speaker12SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker12SpinnerLabel.Text = 'Speaker 12';
            app.Speaker12Spinner = uispinner(app.CalibrationTab);
            app.Speaker12Spinner.Step = 0.1;
            app.Speaker12Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker12SpinnerValueChanged, true);
            app.Speaker12Spinner.Value = 1;
            app.Speaker13SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker13SpinnerLabel.Text = 'Speaker 13';
            app.Speaker13Spinner = uispinner(app.CalibrationTab);
            app.Speaker13Spinner.Step = 0.1;
            app.Speaker13Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker13SpinnerValueChanged, true);
            app.Speaker13Spinner.Value = 1;
            app.Speaker14SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker14SpinnerLabel.Text = 'Speaker 14';
            app.Speaker14Spinner = uispinner(app.CalibrationTab);
            app.Speaker14Spinner.Step = 0.1;
            app.Speaker14Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker14SpinnerValueChanged, true);
            app.Speaker14Spinner.Value = 1;
            app.Speaker15SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker15SpinnerLabel.Text = 'Speaker 15';
            app.Speaker15Spinner = uispinner(app.CalibrationTab);
            app.Speaker15Spinner.Step = 0.1;
            app.Speaker15Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker15SpinnerValueChanged, true);
            app.Speaker15Spinner.Value = 1;
            app.Speaker16SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker16SpinnerLabel.Text = 'Speaker 16';
            app.Speaker16Spinner = uispinner(app.CalibrationTab);
            app.Speaker16Spinner.Step = 0.1;
            app.Speaker16Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker16SpinnerValueChanged, true);
            app.Speaker16Spinner.Value = 1;
            app.Speaker17SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker17SpinnerLabel.Text = 'Speaker 17';
            app.Speaker17Spinner = uispinner(app.CalibrationTab);
            app.Speaker17Spinner.Step = 0.1;
            app.Speaker17Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker17SpinnerValueChanged, true);
            app.Speaker17Spinner.Value = 1;
            app.Speaker18SpinnerLabel = uilabel(app.CalibrationTab);
            app.Speaker18SpinnerLabel.Text = 'Speaker 18';
            app.Speaker18Spinner = uispinner(app.CalibrationTab);
            app.Speaker18Spinner.Step = 0.1;
            app.Speaker18Spinner.ValueChangedFcn = createCallbackFcn(app, @Speaker18SpinnerValueChanged, true);
            app.Speaker18Spinner.Value = 1;
            app.FFOutput1EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput1EditFieldLabel.Text = 'FF Output 1';
            app.FFOutput1EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput1EditField.Limits = [1 18];
            app.FFOutput1EditField.ValueChangedFcn = createCallbackFcn(app, @FFOutput1EditFieldValueChanged, true);
            app.FFOutput1EditField.Value = 1;
            app.SpeakersLabel = uilabel(app.CalibrationTab);
            app.SpeakersLabel.Text = 'Speakers';
            app.Label_9 = uilabel(app.CalibrationTab);
            app.Label_9.Text = {'Please let the software to know which'; ' speakers are connected to the '; 'output so the correct calibration '; 'numbers will be taken into account'};
            app.FFOutput2EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput2EditFieldLabel.Text = 'FF Output 2';
            app.FFOutput2EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput2EditField.Limits = [1 18];
            app.FFOutput2EditField.ValueChangedFcn = createCallbackFcn(app, @FFOutput2EditFieldValueChanged, true);
            app.FFOutput2EditField.Value = 2;
            app.FFOutput4EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput4EditFieldLabel.Text = 'FF Output 4';
            app.FFOutput4EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput4EditField.Limits = [1 18];
            app.FFOutput4EditField.ValueChangedFcn = createCallbackFcn(app, @FFOutput4EditFieldValueChanged, true);
            app.FFOutput4EditField.Value = 4;
            app.FFOutput3EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput3EditFieldLabel.Text = 'FF Output 3';
            app.FFOutput3EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput3EditField.Limits = [1 18];
            app.FFOutput3EditField.ValueChangedFcn = createCallbackFcn(app, @FFOutput3EditFieldValueChanged, true);
            app.FFOutput3EditField.Value = 3;
            app.FFOutput5EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput5EditFieldLabel.Text = 'FF Output 5';
            app.FFOutput5EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput5EditField.Limits = [1 18];
            app.FFOutput5EditField.ValueChangedFcn = createCallbackFcn(app, @FFOutput5EditFieldValueChanged, true);
            app.FFOutput5EditField.Value = 5;
            app.FFOutput6EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput6EditFieldLabel.Text = 'FF Output 6';
            app.FFOutput6EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput6EditField.Limits = [1 18];
            app.FFOutput6EditField.ValueChangedFcn = createCallbackFcn(app, @FFOutput6EditFieldValueChanged, true);
            app.FFOutput6EditField.Value = 6;
            app.FFOutput8EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput8EditFieldLabel.Text = 'FF Output 8';
            app.FFOutput8EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput8EditField.Limits = [1 18];
            app.FFOutput8EditField.ValueChangedFcn = createCallbackFcn(app, @FFOutput8EditFieldValueChanged, true);
            app.FFOutput8EditField.Value = 8;
            app.FFOutput7EditFieldLabel = uilabel(app.CalibrationTab);
            app.FFOutput7EditFieldLabel.Text = 'FF Output 7';
            app.FFOutput7EditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FFOutput7EditField.Limits = [1 18];
            app.FFOutput7EditField.ValueChangedFcn = createCallbackFcn(app, @FFOutput7EditFieldValueChanged, true);
            app.FFOutput7EditField.Value = 7;
            app.CalibExportButton_2 = uibutton(app.CalibrationTab, 'push');
            app.CalibExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalibExportButton_2Pushed, true);
            app.CalibExportButton_2.Text = 'Export';
            app.ImportButton_2 = uibutton(app.CalibrationTab, 'push');
            app.ImportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_2Pushed, true);
            app.ImportButton_2.Text = 'Import';
            app.Label_10 = uilabel(app.CalibrationTab);
            app.Label_10.Text = {'A Linear Gain (in db) map for each speaker in the room'; 'Gain = 1 means no change in the output'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TDT_GUI_v3_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end